classdef SetupWizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NansenSetupUIFigure            matlab.ui.Figure
        CloseDialogButton              matlab.ui.control.Image
        FolderHierarchyExampleImage    matlab.ui.control.Image
        NavigationPanel                matlab.ui.container.Panel
        NansenLogoImage                matlab.ui.control.Image
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        IntroTab                       matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        LoadingImage                   matlab.ui.control.Image
        InfoIconLabel                  matlab.ui.control.Label
        InfoIcon                       matlab.ui.control.Image
        EyeIconLabel                   matlab.ui.control.Label
        EyeIcon                        matlab.ui.control.Image
        HelpIconLabel                  matlab.ui.control.Label
        HelpIcon                       matlab.ui.control.Image
        WelcomeLabel                   matlab.ui.control.Label
        IntroText                      matlab.ui.control.Label
        AddonsTab                      matlab.ui.container.Tab
        ShowMoreOptionsButton          matlab.ui.control.StateButton
        DownloadToolboxesLabel_2       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        AddonsPanel                    matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        ProjectTab                     matlab.ui.container.Tab
        Label_12                       matlab.ui.control.Label
        ProjectHeading                 matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ProjectPanel                   matlab.ui.container.Panel
        ModulesTab                     matlab.ui.container.Tab
        ModulePanel                    matlab.ui.container.Panel
        Label_17                       matlab.ui.control.Label
        ModuleHeading                  matlab.ui.control.Label
        DataLocationsTab               matlab.ui.container.Tab
        DataLocationsTitleLabel        matlab.ui.control.Label
        DataLocationDescriptionLabel   matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        DataLocationPanel              matlab.ui.container.Panel
        FolderHierarchyTab             matlab.ui.container.Tab
        FolderHierarchyExampleInfoButton  matlab.ui.control.Image
        Label_15                       matlab.ui.control.Label
        SpecifyDataOrganizationLabel   matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        FolderOrganizationInputPanel   matlab.ui.container.Panel
        MetadataTab                    matlab.ui.container.Tab
        MetadataPanel                  matlab.ui.container.Panel
        Label_7                        matlab.ui.control.Label
        MetadataHeading                matlab.ui.control.Label
        VariablesTab                   matlab.ui.container.Tab
        Label_16                       matlab.ui.control.Label
        VariableEditorPanel            matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        DataVariablesLabel             matlab.ui.control.Label
        SchemaTab                      matlab.ui.container.Tab
        SchemaPanel                    matlab.ui.container.Panel
        SchemaHelpButton               matlab.ui.control.Image
        SelectSchemaTemplateDropDown   matlab.ui.control.DropDown
        SelectSchemaTemplateLabel      matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        SelectSessionSchemaLabel       matlab.ui.control.Label
        AddonsAdvancedTab              matlab.ui.container.Tab
        DownloadToolboxesLabel         matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        AddonsPanelAdvanced            matlab.ui.container.Panel
        ToolboxTab                     matlab.ui.container.Tab
        HelpAddonManager               matlab.ui.control.Image
        AddonInstructionLabel          matlab.ui.control.Label
        ToolboxPanel                   matlab.ui.container.Panel
        SkipToolboxPageButton          matlab.ui.control.Button
        AddonInstallationNote          matlab.ui.control.Label
        MatlabAddonExplorerScreenshot  matlab.ui.control.Image
        InstallToolboxesLabel          matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
    end

    % Color & Appearance Properties Todo: Should be a theme...
    properties
        FontName = 'Segoe UI';
        
        % Eksempel for hex definisjon:
        % GenericButtonFontColor = hex2rgb('000000');
        
        % Standard button
        GenericButtonFontColor = [0,0,0];
        GenericButtonBackgroundColor = [0.9,0.9,0.9];
        
        % Install button
        InstallButtonFontColor = [0.8784 0.8784 0.7059];
        InstallButtonBackgroundColor = [0 0.6 0];
        
        % Dropdown menu
        DropdownMenuBackgroundColor = [1,1,1];
    end

    properties (Access=private)
        CurrentPageNumber = 1
        VisibleTabPages = ["IntroTab", "AddonsTab", "ProjectTab", "ModulesTab", "DataLocationsTab", "FolderHierarchyTab", "MetadataTab", "VariablesTab"]
    end
    
    properties (Access = private) % Data models & UI Interfaces for tabs
        ProjectManager   nansen.config.project.ProjectManager               % Manager for projects
        ProjectManagerUI nansen.config.project.ProjectManagerUI             % User interface for the project manager
        
        ModuleManager nansen.config.module.ModuleManager 
        ModuleManagerUI nansen.config.module.ModuleManagerUI

        AddonManager   % Interface for installing addons/toolboxes
        AddonUiTable   % UI Table for displaying list of supported addons
        
        DataLocationModel
        DataLocationModelUI
        FolderOrganizationUI
        MetadataInitializationUI
        
        VariableModel
        VariableModelUI
        
        TabUpdateRequired = struct('IntroTab', false, 'AddonsTab', false, ...
            'ProjectTab', false, 'ModulesTab', false, 'SchemaTab', false, ...
            'DataLocationsTab', true, 'FolderHierarchyTab', true, ...
            'MetadataTab', false, 'VariablesTab', false)
        
        ProjectCreatedListener
        ProjectChangedListener
    end
    
    properties (Access = private) % App components that are created programmatically
        PageIndicator % Description
        NextButton2
        PreviousButton2
    end
    
    
    methods (Access = private) % Methods for creating pages
        
        function createPagesOnStartup(app)
            
            % Create table for addon/toolbox manager
            if any(strcmp(app.VisibleTabPages, "AddonsTab"))
                app.createAddonTable()
            end

            % Create page for project manager
            app.ProjectManagerUI = nansen.config.project.ProjectManagerUI(app.ProjectPanel);
            app.ProjectManager = app.ProjectManagerUI.ProjectManager;
            
            % Todo: Listen for CurrentProjectSet from ProjectManager...
            app.ProjectChangedListener = listener(app.ProjectManager, ...
                'CurrentProjectSet', @app.onProjectChanged);
            
            % Skip the next steps...
            if app.ProjectManager.NumProjects == 0 || isempty(nansen.getCurrentProject)
                return
            end

            % Create project-dependent pages
            app.createModuleManagerUI()
            app.createDataLocationsUI()
            app.createVariableEditorPage()
        end 
        
        function createToolboxPage(app, toolboxNames)
            
            xPos = 70;
            yPos = 220;
            
            editFieldHeight = 22;
            editFieldSpacing = 10;
            
            numToolboxes = numel(toolboxNames);
            
            for i = 1:numToolboxes
                number = uilabel(app.ToolboxPanel);
                number.Text = sprintf('%d.', i);
                number.Position = [xPos-20, yPos, 22,22];
                
                nameField = uieditfield(app.ToolboxPanel, 'text');
                nameField.Value = toolboxNames{i};
                nameField.Editable = false;

%                 nameField = uilabel(app.ToolboxPanel);
%                 nameField.Text = toolboxNames{i};

                nameField.BackgroundColor = [0.9412 0.9412 0.9412];
                w = numel(toolboxNames{i}) * 12 * 0.55;
                nameField.Position = [xPos, yPos, w, editFieldHeight];
                
                yPos = yPos - editFieldHeight - editFieldSpacing;
            end
        end
        
        function createAddonTable(app)
        %createAddonTable Create a table of supported addons

            userSession = nansen.internal.user.NansenUserSession.instance();
            app.AddonManager = userSession.getAddonManager();
            
            % This will create the advanced options for addon installation
            hPanel = app.AddonsPanelAdvanced;
            args = {hPanel, app.AddonManager};

            app.AddonUiTable = nansen.config.addons.AddonManagerUI(args{:});

            % This will create the default view for addon installation
            data = app.AddonManager.AddonList;
            isInstalled = [data.IsInstalled];
            
            names = {data(~isInstalled).Name}';
            descriptions = {data(~isInstalled).Description}';
            isRequired = [data(~isInstalled).IsRequired]';
            
            requiredLabel = cell(size(names));
            requiredLabel(isRequired) = {'Required'};
            requiredLabel(~isRequired) = {'Optional'};
            
            if isempty(names)
                app.UITable.Visible = 'off';
                % Todo: Just skip page altogether
                app.Label_13.Text = 'All packages are installed. You can proceed';
            else
                app.UITable.Data = [names, descriptions, requiredLabel];
            end
        end

        function createModuleManagerUI(app)
        % Create UI for Module Manager

            if ~isempty( app.ModuleManagerUI ) % Delete if it exists. Todo: Should not have to do this
                delete( app.ModuleManagerUI )
            end

            app.ModuleManagerUI = nansen.config.module.ModuleManagerUI(app.ModulePanel);
            app.ModuleManager = app.ModuleManagerUI.ModuleManager;
            
            p = app.ProjectManager.getCurrentProject();

            if ~isempty(p) && isfield(p.Preferences, 'DataModule')
                projectModules = p.Preferences.DataModule;
            else
                projectModules = string.empty;
            end

            app.ModuleManagerUI.setSelectedModules(projectModules);

            addlistener(app.ModuleManagerUI, 'ModuleSelectionChanged', ...
                @app.onModuleSelectionChanged);
        end

        function createDataLocationsUI(app)
            
            if ~isempty( app.DataLocationModelUI ) % Delete if it exists. Todo: Should not have to do this
                delete( app.DataLocationModelUI )
            end
            
            % Get current data location model....
            dataLocationModel = nansen.DataLocationModel();
            if isempty(dataLocationModel)
                return
            else
                app.DataLocationModel = dataLocationModel;
            end
            
            % Create components for the DataLocationModel configuration
            h = nansen.config.dloc.DataLocationModelApp(...
                'DataLocationModel', app.DataLocationModel, ...
                'ControlPanels', [app.DataLocationPanel, app.FolderOrganizationInputPanel, app.MetadataPanel], ...
                'TabGroup', app.TabGroup, ...
                'TabList', [app.DataLocationsTab, app.FolderHierarchyTab, app.MetadataTab]);
            
            % Todo: assign to properties
            h.createUIModules(1)
            h.createUIModules(2, 'FolderListViewerActive', true)
            h.createUIModules(3)
            
            app.DataLocationModelUI = h;
        end
        
        function createVariableEditorPage(app)
        %createVariableEditorPage create ui for variable model.
                    
            if ~isempty( app.VariableModelUI ) % Delete if it exists. Todo: Should not have to do this
                delete( app.VariableModelUI )
            end

            app.VariableModel = nansen.VariableModel();
            if isempty(app.VariableModel); return; end
            
            app.VariableModel.addDataLocationModel(app.DataLocationModel);
            
            args = {'DataLocationModel', app.DataLocationModel, ...
                'VariableModel', app.VariableModel, ...
                'Data', app.VariableModel.Data };
            
            app.VariableModelUI = nansen.config.varmodel.VariableModelUI(app.VariableEditorPanel, args{:});
            %app.VariableModelUI.hidePresetVariables()
            %app.VariableModelUI.UIButton_ToggleVariableVisibility.Text = 'Show all variables...';
            app.VariableModelUI.UIButton_AddVariable.Enable = 'off';
        end
                
        function createPageSteps(app, S)
            
            uicc = uim.UIComponentCanvas(app.NavigationPanel);

            %obj.h.ButtonPrevious = uim.control.Button(uicc, 'String', 'Previous', 'Size', buttonSize, 'Location', 'southwest', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [180, 70, 0, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('previous'));
            %obj.h.ButtonNext = uim.control.Button(uicc, 'String', 'Next', 'Size', buttonSize, 'Location', 'southeast', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [0, 70, 180, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('next'));
            
            options = {'Size', [inf, 60], 'Margin', [13, 13, 20, 20], ...
                'Location', 'southwest', 'IndicatorSize', 10, ...
                'SizeMode', 'manual', 'BarColor', S.FigureBgColor, ...
                'FontColor', S.FigureBgColor, 'IndicatorColor', S.HeaderMidColor, ...
                'FontSize', 14, 'IndicatorSize', 12, 'BlockChangePage', true};

            numPages = numel(app.VisibleTabPages);
            pageNames = cell(1, numPages);
            for i = 1:numPages
                pageNames{i} = app.(app.VisibleTabPages(i)).Title;
            end

            app.PageIndicator = uim.widget.PageIndicator(uicc, pageNames, options{:});
            app.PageIndicator.ChangePageFcn = @app.onPageSwitcherPressed;
        end
        
        function progressDialog = openProgressDialog(app, message, title, varargin)
            
            try
                progressDialog = uiprogressdlg(app.NansenSetupUIFigure, ...
                    'Message', message, 'Title', title, varargin{:});
            catch
                progressDialog = uiprogressdlg(app.NansenSetupUIFigure, ...
                    message, title, varargin{:});
            end

            
        end
    end
    
    methods (Access = private) % Methods for updating pages
    
        function onChangePagePressed(app, direction)
        %onChangePagePressed Callback for clicks on next/previous buttons
        %
        %   Initialize changing page
        
            switch direction
                case 'previous'
                    incr = -1;
                case 'next'
                    incr = 1;
            end

            newPageNumber = app.CurrentPageNumber + incr;
            
            % Don't switch to tabs outside range
            if newPageNumber < 1 || newPageNumber > numel(app.VisibleTabPages)
                return
            end

            app.changeTab(app.CurrentPageNumber, newPageNumber)
        end
        
        function onPageSwitcherPressed(app, ~, evt)
        %onPageSwitcherPressed Callback for clicks on pageswitcher bar    
            %   Initialize changing page
            app.changeTab(evt.OldPageNumber, evt.NewPageNumber)
        end
        
        function changeTab(app, currentTabNum, newTabNum)
        %changeTab Change tabs and do all the internal work needed.
        
            % Todo: Use tab numbers or tab titles as inputs?
            
            currentTabName = app.VisibleTabPages(currentTabNum);
            currentTab = app.(currentTabName);

            newTabName = app.VisibleTabPages(newTabNum);
            newTab = app.(newTabName);
            
            % Check if tab is completed and we can move on
            isCompleted = app.checkIfTabCompleted(currentTab.Title);
            if ~isCompleted
                return
            end
            
            app.exitTab(currentTab.Title)
            
            % Change the selected tab.
            app.TabGroup.SelectedTab = newTab;
            app.CurrentPageNumber = newTabNum;

            % Call method to update the selected tab.
            app.enterTab(newTab)
            
            app.PageIndicator.changePage(newTabNum)
            
            % Turn off navigation bar if going back to Welcome page 
            if strcmp(newTab.Title, 'Intro')
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'off';
                app.PreviousButton2.Visible = 'off';
            else
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'on';
                app.PreviousButton2.Visible = 'on';
            end
            
% %             if newTabNum == numel(app.VisibleTabPages)
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.finish;
% %             else
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.next2;
% %             end
        end
        
        function isCompleted = checkIfTabCompleted(app, tabName)
            
            isCompleted = false;
            msg = '';

            switch tabName
                case 'Project'                    
                    if app.ProjectManager.NumProjects ~= 0
                        if app.ProjectManagerUI.isProjectInformationEntered() && ~app.ProjectManagerUI.wasProjectCreated()
                            [answer, wasCreated] = app.promptCreateNewProject();
                            if strcmp(answer, 'OK') && wasCreated
                                isCompleted = true;
                            end
                        else
                            isCompleted = true;
                            return
                        end
                       
                    elseif ~app.ProjectManagerUI.isProjectInformationEntered()
                        msg = 'You need to enter a project name before proceeding';
                        title = 'Project name missing';
                        isCompleted = false;
                    else
                        if ~app.ProjectManagerUI.wasProjectCreated()
                            [answer, wasCreated] = app.promptCreateNewProject();
                            if strcmp(answer, 'OK') && wasCreated
                                isCompleted = true;
                            end
                        end
                    end
                    
                case 'Data Locations'
                    dlModule = app.DataLocationModelUI.UIModule{1};
                    [isCompleted, msg] = dlModule.isTableCompleted();
                otherwise
                    isCompleted = true;
            end
            
            if ~isCompleted && ~isempty(msg)
                uialert(app.NansenSetupUIFigure, msg, 'Please enter missing information', 'Icon', 'info')
            end
        end
        
        function exitTab(app, tabName)
        %exitTab Check for changes on interdependent tabs
        %
        %   If there has been changes to the data model on a given tab, a
        %   dependent tab is flagged for update and the datamodel is
        %   updated.
            
            switch tabName
                
                case 'Addons'
                    if app.ShowMoreOptionsButton.Value

                        if app.AddonManager.IsDirty
                            message = 'Do you want to add downloaded toolboxes to the matlab path?';
                            title = 'Update Matlab''s savepath?';
                
                            selection = uiconfirm(app.NansenSetupUIFigure, message, ...
                                title, 'Options',{'Yes','Skip'},...
                                'DefaultOption',1,'CancelOption',2);
                            
                            if strcmp(selection, 'Yes')
                                savepath
                                app.AddonManager.markClean()
                                app.AddonManager.restoreAddToPathOnInitFlags()
                            end
                        end
                        
                    else
                        % By default, we should install all toolboxes
                        app.AddonUiTable.downloadAllAddons()
                        savepath
                        app.AddonManager.markClean()
                        app.AddonManager.restoreAddToPathOnInitFlags()
                    end
                    
                case 'Project'

                case 'Modules'
                    % Changes are handled by notifications/listeners
                    
                case 'Data Locations'
                    app.DataLocationModelUI.UIModule{1}.setInactive()
                    
                case 'Folder Organization'
                    app.DataLocationModelUI.UIModule{2}.setInactive()

                case 'Metadata'
                    app.DataLocationModelUI.UIModule{3}.setInactive()
            end
        end
        
        function enterTab(app, uiTab)
        %enterTab Update tab when it is selected                
            
            switch uiTab.Title
                case 'Data Locations'
                    if ~isempty(app.DataLocationModelUI) % Precaution
                        app.DataLocationModelUI.UIModule{1}.setActive()
                    end
                    
                case 'Folder Organization'
                    app.DataLocationModelUI.UIModule{2}.setActive()
                    if app.TabUpdateRequired.FolderHierarchyTab
                        app.DataLocationModelUI.UIModule{2}.refreshDataLocationTemplates()
                        app.TabUpdateRequired.FolderHierarchyTab = false;
                    end
                    
                case 'Modules'
                    %p = app.ProjectManager.getCurrentProject();
                    %app.ModuleManagerUI.setSelectedModules(p.Preferences.DataModule );

                case 'Metadata'
                    app.DataLocationModelUI.UIModule{3}.setActive()
                    
                case 'Variables'
                    if app.TabUpdateRequired.VariablesTab
                        p = app.ProjectManager.getCurrentProject();
                        dataVariables = table2struct(p.getTable('DataVariable'));
                        app.VariableModelUI.updateFromList(dataVariables)
                        app.TabUpdateRequired.VariablesTab = false;
                    end
            end
        end
    
        function [answer, wasCreated] = promptCreateNewProject(app)

            wasCreated = false;
            iconPathName = fullfile( fileparts(mfilename('fullpath')), 'resources', 'icons', 'help.png');
            answer = uiconfirm(app.NansenSetupUIFigure, ...
                'Create new project?', 'Create Project', 'Icon', iconPathName);
            
            switch answer
                case 'OK'
                    progressDialog = app.openProgressDialog(...
                        'Creating new project...', 'Please wait', 'Indeterminate', 'on');
                    try
                        app.ProjectManagerUI.createProject()
                        wasCreated = true;
                        progressDialog.Indeterminate = 'off';
                        progressDialog.Message = 'Created project!';
                        pause(0.5)
                        delete(progressDialog)
                    catch ME
                        delete(progressDialog);
                        uialert(app.NansenSetupUIFigure, 'Failed to create new project')
                        disp(getReport(ME))
                    end

                    % Todo: Give feedback to user that the
                    % project was created.
                case 'Cancel'
                    return
            end
        end

        function finishSetup(app)
        %finishSetup Finish the setup based on user configurations
        
            % Todo: Save the Data Location model and the filepath settings.
            
            % Todo: Make external function
                        
% % % % % % Save changes from data location and folder organization pages. 
            
            % Save the data location model
            
            for i = 2:3
                if ~isempty(app.DataLocationModelUI.UIModule{i})
                    app.DataLocationModelUI.UIModule{i}.updateDataLocationModel()
                    app.DataLocationModelUI.UIModule{i}.markClean()
                end
            end
            
            %app.DataLocationModel.refreshFilePath()
            app.DataLocationModel.save()

% % % % % % Save changes from data variables page 
            S = app.VariableModelUI.getUpdatedTableData();
            app.VariableModel.setVariableList(S)
            app.VariableModel.save()
            
            % Todo: Get schema based on selection
            sessionSchema = @nansen.metadata.type.Session;
            
            try
                % Get default metatable item from the metatable catalog
                project = app.ProjectManager.getCurrentProject();            
                MTC = project.MetaTableCatalog;

                % Does metatable exist from before?                
                if ~isempty(MTC.Table)
                    metaTableExists = any( strcmp(MTC.Table.MetaTableName, 'Session') );
                else
                    metaTableExists = false;
                end
                
                createMetaTable = true;
                
                if metaTableExists
                    iconPathName = fullfile( fileparts(mfilename('fullpath')), 'resources', 'icons', 'help.png');
                    selection = uiconfirm(app.NansenSetupUIFigure, ...
                        ['A Metatable for this project already exists. ' ...
                        'Do you want to replace the existing table?'], ...
                        'Override Existing Metatable?', ...
                        'Options', {'Replace', 'Keep Current', 'Cancel'}, ...
                        'Icon', iconPathName);
                    
                    switch selection
                        case 'Replace'
                            createMetaTable = true;
                        case 'Keep Current'
                            createMetaTable = false;
                        otherwise
                            return
                    end
                end
                
                if createMetaTable
                    d = uiprogressdlg(app.NansenSetupUIFigure, ...
                        'Title', 'Detecting Sessions', 'Indeterminate','on');
                    wasAborted = nansen.config.initializeSessionTable(app.DataLocationModel, sessionSchema, d, app.NansenSetupUIFigure);
                    close(d)

                    if wasAborted
                        uialert(app.NansenSetupUIFigure, 'Aborted while creating a session table', 'Aborted')
                        return
                    end
                end
                
                % Open nansen app
                nansen
                
                % Close configuration app
                close(app.NansenSetupUIFigure)
            catch ME
                uialert(app.NansenSetupUIFigure, ME.message, ME.identifier)
                rethrow(ME)
            end    
        end
    
    end
        
    methods

        function changePage(app, direction)
            % Simulate a button press.
            arguments
                app (1,1) nansen.app.setup.SetupWizard
                direction (1,1) string {mustBeMember(direction, ["previous", "next"])} = "next"
            end
            app.onChangePagePressed(direction)
        end
        
        function onProjectChanged(app, ~, ~)
        %onProjectCreated Update dependent pages when current project change
        
            % Show waitbar...
            d = uiprogressdlg(app.NansenSetupUIFigure, ...
                'Title', 'Updating pages...', 'Indeterminate','on');
            
            app.createModuleManagerUI()

            d.Message = 'Initializing DataLocation Model for project...';
            app.createDataLocationsUI()
            
            d.Message = 'Initializing Variable Model for project...';
            app.createVariableEditorPage()
            
            delete(d)

            % Todo: necessary?
            app.TabUpdateRequired.DataLocationsTab = true;
            app.TabUpdateRequired.FolderHierarchyTab = true;
            app.TabUpdateRequired.MetadataTab = true;
            app.TabUpdateRequired.VariablesTab = true;
        end
    
        function onModuleSelectionChanged(app, ~, evtData)
            
            % Get current project
            p  = app.ProjectManager.getCurrentProject();

            % Update the optional modules for the project
            p.setOptionalModules( {evtData.SelectedData.PackageName} )
            app.TabUpdateRequired.FolderHierarchyTab = true;
            app.TabUpdateRequired.VariablesTab = true; 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pagesToShow)
            
            if nargin >= 2
                app.VisibleTabPages = pagesToShow;
            end
            
            % Create function....
            pageNames = cell(1, numel(app.VisibleTabPages));
            for i = 1:numel(app.VisibleTabPages)
                pageNames{i} = app.(app.VisibleTabPages(i)).Title;
            end
            
            isFirstTab = strcmp({app.TabGroup.Children.Title}, pageNames{1});
            if find(isFirstTab, 1, 'first') ~= 1
                app.TabGroup.SelectedTab = app.TabGroup.Children(isFirstTab);
                progressdlg = app.openProgressDialog('Loading, please wait.', '', 'Indeterminate', 'on');
            end

            userSession = nansen.internal.user.NansenUserSession.instance('', "nocreate");
            if isempty(userSession)
                % Initialize a nansen user session
                nansen.internal.user.NansenUserSession.instance('', "check", true);
            end

            hFig = app.NansenSetupUIFigure; % Just to shorten variablename
            uim.utility.centerFigureOnScreen(hFig)
            
            S = nansen.theme.getThemeColors('deepblue');

            app.NavigationPanel.BackgroundColor = S.HeaderBgColor;

            % Find and set background colors of tabs
            hTabs = app.TabGroup.Children;
            set(hTabs, 'BackgroundColor', S.FigureBgColor)

            % Find handles for all page title labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageTitleLabel'};
            hPageTitleLabels = findobj(hFig, tokens{:});
            set(hPageTitleLabels, 'BackgroundColor', S.HeaderBgColor, 'FontColor', S.HeaderFgColor, 'Position', [1,511,786,78], 'VerticalAlignment', 'bottom')
            
            % Find handles for all page description labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageDescriptionLabel'};
            hPageDescriptionLabels = findobj(hFig, tokens{:});
            set(hPageDescriptionLabels, 'BackgroundColor', 'None')
            
            app.FolderHierarchyExampleImage.Position(1:2) = [1,1];
            
            app.IntroText.Interpreter = 'html';
            
            app.StartButton.Text = 'Preparing...';
            app.StartButton.Enable = 'off';
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
            drawnow
            pause(0.05)
            
            try
                % This will fail on github runner
                figure(hFig)
            end
            
            % Create pages
            app.createPagesOnStartup()
                        
            % Customize appeareance of buttons and controls
            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uibutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)

            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uistatebutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)
            
            hDropdown = findobj(app.NansenSetupUIFigure, 'type', 'uidropdown');
            set(hDropdown, 'BackgroundColor', app.DropdownMenuBackgroundColor)
            set(hDropdown, 'FontName', app.FontName)

            app.AddonUiTable.ToolbarButtonBackgroundColor='w';

            app.createPageSteps(S)
            
%             uicc = uim.UIComponentCanvas(app.NavigationPanel);
% 
%             %obj.h.ButtonPrevious = uim.control.Button(uicc, 'String', 'Previous', 'Size', buttonSize, 'Location', 'southwest', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [180, 70, 0, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('previous'));
%             %obj.h.ButtonNext = uim.control.Button(uicc, 'String', 'Next', 'Size', buttonSize, 'Location', 'southeast', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [0, 70, 180, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('next'));
%             
%             % Create page indicator
%             options = {'Size', [inf, 60], 'Margin', [13, 13, 20, 20], ...
%                 'Location', 'southwest', 'IndicatorSize', 10, ...
%                 'SizeMode', 'manual', 'BarColor', S.FigureBgColor, ...
%                 'FontColor', S.FigureBgColor, 'IndicatorColor', S.HeaderMidColor, ...
%                 'FontSize', 14, 'IndicatorSize', 12, 'BlockChangePage', true};
% 
%             pageNames = {app.TabGroup.Children(1:end-3).Title};
%             app.PageIndicator = uim.widget.PageIndicator(uicc, pageNames, options{:});
%             app.PageIndicator.ChangePageFcn = @app.onPageSwitcherPressed;
            
            
            ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
            
            app.PreviousButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.prev2, 'Position', [285,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [10,10,10,10], 'MechanicalAction', 'Latch when released', ...
                'Callback', @(src,evt) app.PreviousButtonPushed(evt), ...
                'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
            
            app.NextButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.next2, 'Position', [425,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [16,10,10,10], 'MechanicalAction', 'Latch when released',...
                'Callback', @(src,evt) app.NextButtonPushed(evt), ...
                 'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
                %'Style', uim.style.buttonSymbol, ...
               
            
% %             btnBgColor = ones(1,3) .* [0    0.3020    0.4980];
% %             btnFgColor = ones(1,3) .* 0.98;
% %             
% %              
% %             app.NextButton.BackgroundColor = btnBgColor;
% %             app.PreviousButton.BackgroundColor = btnBgColor;
% %             app.NextButton.FontColor = btnFgColor;
% %             app.PreviousButton.FontColor = btnFgColor;
            
            drawnow

            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = '';
            
            app.StartButton.Text = 'Let''s go!';
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
                       
            if find(isFirstTab, 1, 'first') ~= 1
                delete(progressdlg)
                app.NextButton2.Visible = 'on';
                app.PreviousButton2.Visible = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            requiredToolboxes = nansen.internal.dependencies.getRequiredMathworksProducts();
            TF = nansen.internal.setup.isToolboxInstalled(requiredToolboxes);
            
            if ~ all(TF)
                gotoNum = contains({app.TabGroup.Children.Title}, 'Toolbox');
                app.createToolboxPage(requiredToolboxes(~TF))
                app.TabGroup.SelectedTab = app.TabGroup.Children(gotoNum);
                app.PageIndicator.Visible = 'off';
                return
            end
            
            app.changeTab(1, 2)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            % Check if this is the last tab and we should finish.
            lastTab = app.(app.VisibleTabPages(end));

            if isequal(app.TabGroup.SelectedTab, lastTab)
                app.finishSetup()
                return
            end
            
            app.onChangePagePressed('next')
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.onChangePagePressed('previous')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        %TabGroupSelectionChanged Do things when tab is selected.
            app.enterTab(app.TabGroup.SelectedTab)
        end

        % Image clicked function: HelpAddonManager
        function HelpAddonManagerClicked(app, event)
            app.MatlabAddonExplorerScreenshot.Visible = 'on';
        end

        % Value changed function: ShowMoreOptionsButton
        function ShowAdvancedAddonOptionsButtonPushed(app, event)
            
            % NB: Exchange AddonsTab and AddonsAdvancedTab in the VisibleTabPages
            % in order to keep page order intact.

            if event.Value
                text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
                
                isOldPage = strcmp(app.VisibleTabPages, 'AddonsTab');
                app.VisibleTabPages(isOldPage) = 'AddonsAdvancedTab';
                
                app.TabGroup.SelectedTab = app.AddonsAdvancedTab;

                app.ShowMoreOptionsButton.Text = 'Show Less Options...';
                app.ShowMoreOptionsButton.Parent = app.AddonsAdvancedTab;
            else
                text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';

                isOldPage = strcmp(app.VisibleTabPages, 'AddonsAdvancedTab');
                app.VisibleTabPages(isOldPage) = 'AddonsTab';
                
                app.TabGroup.SelectedTab = app.AddonsTab;

                app.ShowMoreOptionsButton.Text = 'Show More Options...';
                app.ShowMoreOptionsButton.Parent = app.AddonsTab;
            end
            
            % Update the description text for current page. 
            app.Label_13.Text = text;
        end

        % Image clicked function: SchemaHelpButton
        function SchemaHelpButtonClicked(app, event)
            error('Not implemented') % todo
            % % schemaName = app.SelectSchemaTemplateDropDown.Value;
            % % msg = nansen.setup.getSchemaDefinition(schemaName);
            % % 
            % % hFigure = app.NansenSetupUIFigure;
            % % title = sprintf('Schema Definition');
            % % iconPathName = fullfile( fileparts(mfilename('fullpath')), 'resources', 'icons', 'info.png');
            % % uialert(hFigure, msg, title, 'Icon', iconPathName)
        end

        % Button pushed function: SkipToolboxPageButton
        function SkipToolboxPageButtonPushed(app, event)
            app.changeTab(1, 2)
            app.PageIndicator.Visible = 'on';
        end

        % Image clicked function: FolderHierarchyExampleInfoButton
        function FolderHierarchyExampleInfoButtonImageClicked(app, event)
            app.FolderHierarchyExampleImage.Visible = 'on';
            app.CloseDialogButton.Visible = 'on';
        end

        % Close request function: NansenSetupUIFigure
        function NansenSetupUIFigureCloseRequest(app, event)
            
            abortClose = false;
            
            % Todo: Prompt to save changes!
            if ~isempty(app.DataLocationModelUI)
                abortClose = app.DataLocationModelUI.promptSaveChanges();
            end
            
            if abortClose
                return
            else
                delete(app)
            end
        end

        % Image clicked function: CloseDialogButton
        function CloseDialogButtonClicked(app, event)
            app.FolderHierarchyExampleImage.Visible = 'off';
            app.CloseDialogButton.Visible = 'off';
        end

        % Image clicked function: EyeIcon
        function EyeIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, preview options are available.', 'Info', 'Icon', 'info')
        end

        % Image clicked function: InfoIcon
        function InfoIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, additional information is available.', 'Info',  'Icon', 'info')
        end

        % Image clicked function: HelpIcon
        function HelpIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, additional help is available.', 'Info', 'Icon', 'info')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NansenSetupUIFigure = uifigure('Visible', 'off');
            app.NansenSetupUIFigure.Name = 'Nansen Setup';
            app.NansenSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @NansenSetupUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.NansenSetupUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.IntroText = uilabel(app.IntroTab);
            app.IntroText.Tag = 'PageDescriptionLabel';
            app.IntroText.Text = {'This dialog will guide you through the initial configuration.'; ''; 'The aims are'; '    1) Install required toolboxes'; '    2) Detect your existing data folders'; '    3) Create a metadata table for your existing data.'};
            app.WelcomeLabel = uilabel(app.IntroTab);
            app.WelcomeLabel.Tag = 'PageTitleLabel';
            app.WelcomeLabel.Text = 'Welcome';
            app.HelpIcon = uiimage(app.IntroTab);
            app.HelpIcon.ImageClickedFcn = createCallbackFcn(app, @HelpIconImageClicked, true);
            app.HelpIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'help.png');
            app.HelpIconLabel = uilabel(app.IntroTab);
            app.HelpIconLabel.Text = 'Press for help';
            app.EyeIcon = uiimage(app.IntroTab);
            app.EyeIcon.ImageClickedFcn = createCallbackFcn(app, @EyeIconImageClicked, true);
            app.EyeIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'look.png');
            app.EyeIconLabel = uilabel(app.IntroTab);
            app.EyeIconLabel.Text = 'Press for preview';
            app.InfoIcon = uiimage(app.IntroTab);
            app.InfoIcon.ImageClickedFcn = createCallbackFcn(app, @InfoIconImageClicked, true);
            app.InfoIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'info.png');
            app.InfoIconLabel = uilabel(app.IntroTab);
            app.InfoIconLabel.Text = 'Press for information';
            app.LoadingImage = uiimage(app.IntroTab);
            app.LoadingImage.ImageSource = fullfile(pathToMLAPP, '..', '..', '..', 'resources', 'images', 'loading.gif');
            app.StartButton = uibutton(app.IntroTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Get started!';
            app.AddonsTab = uitab(app.TabGroup);
            app.AddonsTab.Title = 'Addons';
            app.AddonsPanel = uipanel(app.AddonsTab);
            app.UITable = uitable(app.AddonsPanel);
            app.UITable.ColumnName = {'Toolbox Name'; 'Description'; 'Optionality'};
            app.UITable.RowName = {};
            app.Label_13 = uilabel(app.AddonsTab);
            app.Label_13.Tag = 'PageDescriptionLabel';
            app.Label_13.Text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';
            app.DownloadToolboxesLabel_2 = uilabel(app.AddonsTab);
            app.DownloadToolboxesLabel_2.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel_2.Text = 'Download Toolboxes';
            app.ShowMoreOptionsButton = uibutton(app.AddonsTab, 'state');
            app.ShowMoreOptionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowAdvancedAddonOptionsButtonPushed, true);
            app.ShowMoreOptionsButton.Text = 'Show More Options...';
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.ProjectPanel = uipanel(app.ProjectTab);
            app.Label_3 = uilabel(app.ProjectTab);
            app.Label_3.Tag = 'PageDescriptionLabel';
            app.Label_3.Text = {'Enter project name and a directory where configuration data will be saved.'; ''; ''};
            app.ProjectHeading = uilabel(app.ProjectTab);
            app.ProjectHeading.Tag = 'PageTitleLabel';
            app.ProjectHeading.Text = 'Create Project';
            app.Label_12 = uilabel(app.ProjectTab);
            app.Label_12.Text = '';
            app.ModulesTab = uitab(app.TabGroup);
            app.ModulesTab.Title = 'Modules';
            app.ModuleHeading = uilabel(app.ModulesTab);
            app.ModuleHeading.Tag = 'PageTitleLabel';
            app.ModuleHeading.Text = 'Select Modules';
            app.Label_17 = uilabel(app.ModulesTab);
            app.Label_17.Tag = 'PageDescriptionLabel';
            app.Label_17.Text = {'Select modules that will be included in your project.'; ''};
            app.ModulePanel = uipanel(app.ModulesTab);
            app.DataLocationsTab = uitab(app.TabGroup);
            app.DataLocationsTab.Title = 'Data Locations';
            app.DataLocationPanel = uipanel(app.DataLocationsTab);
            app.Label_14 = uilabel(app.DataLocationsTab);
            app.Label_14.Text = 'Optional: You can add multiple data locations. This is relevant if different types of raw data, i.e imaging data and behavior data, are stored in different locations, or for processed data etc.';
            app.DataLocationDescriptionLabel = uilabel(app.DataLocationsTab);
            app.DataLocationDescriptionLabel.Tag = 'PageDescriptionLabel';
            app.DataLocationDescriptionLabel.Text = {'Enter a root directory where raw data for the new project is located.'; ''};
            app.DataLocationsTitleLabel = uilabel(app.DataLocationsTab);
            app.DataLocationsTitleLabel.Tag = 'PageTitleLabel';
            app.DataLocationsTitleLabel.Text = 'Add Data Locations';
            app.FolderHierarchyTab = uitab(app.TabGroup);
            app.FolderHierarchyTab.Title = 'Folder Organization';
            app.FolderOrganizationInputPanel = uipanel(app.FolderHierarchyTab);
            app.Label_5 = uilabel(app.FolderHierarchyTab);
            app.Label_5.Tag = 'PageDescriptionLabel';
            app.Label_5.Text = 'How are data subfolders organized within the root folder? Add new subfolder level(s) using the + button.';
            app.SpecifyDataOrganizationLabel = uilabel(app.FolderHierarchyTab);
            app.SpecifyDataOrganizationLabel.Tag = 'PageTitleLabel';
            app.SpecifyDataOrganizationLabel.Text = 'Specify Data Organization';
            app.Label_15 = uilabel(app.FolderHierarchyTab);
            app.Label_15.Text = 'Optional:  Do you want to exclude some folders? Apply inclusion and exclusion criteria on foldernames under filter options.';
            app.FolderHierarchyExampleInfoButton = uiimage(app.FolderHierarchyTab);
            app.FolderHierarchyExampleInfoButton.ImageClickedFcn = createCallbackFcn(app, @FolderHierarchyExampleInfoButtonImageClicked, true);
            app.FolderHierarchyExampleInfoButton.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'info.png');
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.MetadataHeading = uilabel(app.MetadataTab);
            app.MetadataHeading.Tag = 'PageTitleLabel';
            app.MetadataHeading.Text = 'Detect Data Information';
            app.Label_7 = uilabel(app.MetadataTab);
            app.Label_7.Tag = 'PageDescriptionLabel';
            app.Label_7.Text = 'Select folders and the part of the folder names that uniquely identifies the specified information:';
            app.MetadataPanel = uipanel(app.MetadataTab);
            app.VariablesTab = uitab(app.TabGroup);
            app.VariablesTab.Title = 'Variables';
            app.DataVariablesLabel = uilabel(app.VariablesTab);
            app.DataVariablesLabel.Tag = 'PageTitleLabel';
            app.DataVariablesLabel.Text = 'Data Variables';
            app.Label_6 = uilabel(app.VariablesTab);
            app.Label_6.Tag = 'PageDescriptionLabel';
            app.Label_6.Text = 'Ok, almost there! Please specify a part of the filename that can uniquely identify the file containing the raw two-photon imaging series.';
            app.VariableEditorPanel = uipanel(app.VariablesTab);
            app.Label_16 = uilabel(app.VariablesTab);
            app.Label_16.Text = 'Optional: Press "Show custom variables" to add your own custom variables.';
            app.SchemaTab = uitab(app.TabGroup);
            app.SchemaTab.Title = 'Schema';
            app.SelectSessionSchemaLabel = uilabel(app.SchemaTab);
            app.SelectSessionSchemaLabel.Tag = 'PageTitleLabel';
            app.SelectSessionSchemaLabel.Text = 'Select Session Schema';
            app.Label_4 = uilabel(app.SchemaTab);
            app.Label_4.Tag = 'PageDescriptionLabel';
            app.Label_4.Text = 'We will create a list of experimental sessions for your project. Select a session schema which is suitable for the experiments of this project (The schema specifies a list of table variables that will be displayed for each session in the list).';
            app.SchemaPanel = uipanel(app.SchemaTab);
            app.SelectSchemaTemplateLabel = uilabel(app.SchemaPanel);
            app.SelectSchemaTemplateLabel.Text = 'Select Schema Template';
            app.SelectSchemaTemplateDropDown = uidropdown(app.SchemaPanel);
            app.SelectSchemaTemplateDropDown.Items = {'Two Photon Session', 'Behavioral Training Session'};
            app.SelectSchemaTemplateDropDown.Value = 'Two Photon Session';
            app.SchemaHelpButton = uiimage(app.SchemaPanel);
            app.SchemaHelpButton.ImageClickedFcn = createCallbackFcn(app, @SchemaHelpButtonClicked, true);
            app.SchemaHelpButton.Tag = 'InstallAddon';
            app.SchemaHelpButton.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'help.png');
            app.AddonsAdvancedTab = uitab(app.TabGroup);
            app.AddonsAdvancedTab.Title = 'Addons (Advanced)';
            app.AddonsPanelAdvanced = uipanel(app.AddonsAdvancedTab);
            app.Label_2 = uilabel(app.AddonsAdvancedTab);
            app.Label_2.Tag = 'PageDescriptionLabel';
            app.Label_2.Text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
            app.DownloadToolboxesLabel = uilabel(app.AddonsAdvancedTab);
            app.DownloadToolboxesLabel.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel.Text = 'Download Toolboxes';
            app.ToolboxTab = uitab(app.TabGroup);
            app.ToolboxTab.Title = 'Toolbox';
            app.Label_11 = uilabel(app.ToolboxTab);
            app.Label_11.Tag = 'PageDescriptionLabel';
            app.Label_11.Text = 'Some MATLAB toolboxes are required for Nansen to work properly. Before we continue, please use MATLAB''s Addon Manager to add the toolboxes listed below. ';
            app.InstallToolboxesLabel = uilabel(app.ToolboxTab);
            app.InstallToolboxesLabel.Tag = 'PageTitleLabel';
            app.InstallToolboxesLabel.Text = 'Install Toolboxes';
            app.ToolboxPanel = uipanel(app.ToolboxTab);
            app.MatlabAddonExplorerScreenshot = uiimage(app.ToolboxPanel);
            app.MatlabAddonExplorerScreenshot.Visible = 'off';
            app.MatlabAddonExplorerScreenshot.ImageSource = fullfile(pathToMLAPP, 'resources', 'locate_matlab_addon_explorer.png');
            app.AddonInstallationNote = uilabel(app.ToolboxPanel);
            app.AddonInstallationNote.Text = 'Note: MATLAB will be restarted after each installation';
            app.SkipToolboxPageButton = uibutton(app.ToolboxPanel, 'push');
            app.SkipToolboxPageButton.ButtonPushedFcn = createCallbackFcn(app, @SkipToolboxPageButtonPushed, true);
            app.SkipToolboxPageButton.Text = 'Skip';
            app.AddonInstructionLabel = uilabel(app.ToolboxTab);
            app.AddonInstructionLabel.Text = 'Go to MATLAB''s Home tab -> Add-Ons -> Get Add-Ons';
            app.HelpAddonManager = uiimage(app.ToolboxTab);
            app.HelpAddonManager.ImageClickedFcn = createCallbackFcn(app, @HelpAddonManagerClicked, true);
            app.HelpAddonManager.Tag = 'InstallAddon';
            app.HelpAddonManager.ImageSource = fullfile(pathToMLAPP, 'resources', 'icons', 'help.png');
            app.NavigationPanel = uipanel(app.NansenSetupUIFigure);
            app.PreviousButton = uibutton(app.NavigationPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.NavigationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.NansenLogoImage = uiimage(app.NavigationPanel);
            app.NansenLogoImage.ImageSource = fullfile(pathToMLAPP, '..', '..', '..', 'resources', 'logo', 'nansen_logo2.png');
            app.FolderHierarchyExampleImage = uiimage(app.NansenSetupUIFigure);
            app.FolderHierarchyExampleImage.Visible = 'off';
            app.FolderHierarchyExampleImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'folder_hierarchy_illustration.png');
            app.CloseDialogButton = uiimage(app.NansenSetupUIFigure);
            app.CloseDialogButton.ImageClickedFcn = createCallbackFcn(app, @CloseDialogButtonClicked, true);
            app.CloseDialogButton.Visible = 'off';
            app.CloseDialogButton.ImageSource = fullfile(pathToMLAPP, 'resources', 'close_dialog_button_icon.png');
            % Show the figure after all components are created
            app.NansenSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetupWizard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NansenSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NansenSetupUIFigure)
        end
    end
end