classdef FileAdapterEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        FileAdapterConfigurationLabel  matlab.ui.control.Label
        ToolbarGridLayout              matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        CreateNewButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileAdapters = nansen.dataio.listFileAdapters();

            T = struct2table(fileAdapters);
            T.FileAdapterName = string(T.FileAdapterName);
            T.FunctionName = string(T.FunctionName);
            T.SupportedFileTypes = string(cellfun(@(c)strjoin(c, ', '), T.SupportedFileTypes, 'UniformOutput', false));
            T.DataType = string(T.DataType);
            
            widgetTable = WidgetTable(app.MainGridLayout, ...
                HeaderBackgroundColor="#FFFFFF", ...
                HeaderForegroundColor = "#002054", ...
                BackgroundColor = 'white', ...
                RowHighlightingEnabled = 'off');
            widgetTable.Layout.Column = 1;
            widgetTable.Layout.Row = 3;

            widgetTable.Data = T;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                nansen.config.fileadapter.action.saveFileAdapterCatalog()
                uialert(app.UIFigure, 'Saved changes', 'Saved Changed', 'Icon', 'success')
            catch ME

            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            try
                fileAdapterPath = nansen.config.fileadapter.action.importFileAdapter();
                % Update GUI/catalog
            catch
                % Show error
            end
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            try
                fileAdapterPath = nansen.config.fileadapter.action.createNewFileAdapter();
                % Update GUI/catalog
            catch
                % Show error
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.ToolbarGridLayout = uigridlayout(app.MainGridLayout);
            app.CreateNewButton = uibutton(app.ToolbarGridLayout, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create New...';
            app.ImportButton = uibutton(app.ToolbarGridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import...';
            app.SaveButton = uibutton(app.ToolbarGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileAdapterConfigurationLabel = uilabel(app.MainGridLayout);
            app.FileAdapterConfigurationLabel.Tag = 'PageTitleLabel';
            app.FileAdapterConfigurationLabel.Text = 'File Adapter Configuration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileAdapterEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end