classdef NansenSetupApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NansenSetupUIFigure           matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        IntroTab                      matlab.ui.container.Tab
        Label_1                       matlab.ui.control.Label
        WelcomeLabel                  matlab.ui.control.Label
        HelpIcon                      matlab.ui.control.Image
        HelpIconLabel                 matlab.ui.control.Label
        EyeIcon                       matlab.ui.control.Image
        EyeIconLabel                  matlab.ui.control.Label
        InfoIcon                      matlab.ui.control.Image
        InfoIconLabel                 matlab.ui.control.Label
        LoadingImage                  matlab.ui.control.Image
        Image9                        matlab.ui.control.Image
        StartButton                   matlab.ui.control.Button
        StartButton_2                 matlab.ui.control.Button
        StartButton_3                 matlab.ui.control.Button
        AddonsTab                     matlab.ui.container.Tab
        AddonsPanel                   matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        Label_13                      matlab.ui.control.Label
        DownloadToolboxesLabel_2      matlab.ui.control.Label
        ShowAdvancedOptionsButton     matlab.ui.control.StateButton
        ProjectTab                    matlab.ui.container.Tab
        ProjectPanel                  matlab.ui.container.Panel
        ChangeProjectFolderButton     matlab.ui.control.Button
        GivetheprojectadescriptionLabel  matlab.ui.control.Label
        ProjectNameEditField          matlab.ui.control.EditField
        LocalpathtosaveprojectconfigurationsLabel  matlab.ui.control.Label
        LocalPathEditField            matlab.ui.control.EditField
        EnterashortprojectnameLabel   matlab.ui.control.Label
        ProjectLabelEditField         matlab.ui.control.EditField
        CreateNewProjectButton        matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        ProjectHeading                matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        DataLocationsTab              matlab.ui.container.Tab
        DataLocationPanel             matlab.ui.container.Panel
        AddDataLocationButton         matlab.ui.control.Button
        Label_14                      matlab.ui.control.Label
        DataLocationDecriptionLabel   matlab.ui.control.Label
        DataLocationsTitleLabel       matlab.ui.control.Label
        FolderHierarchyTab            matlab.ui.container.Tab
        FolderOrganizationInputPanel  matlab.ui.container.Panel
        Label_5                       matlab.ui.control.Label
        SpecifyDataOrganizationLabel  matlab.ui.control.Label
        PreviewFolderListImage        matlab.ui.control.Image
        ShowFilterOptionsButton       matlab.ui.control.StateButton
        Label_15                      matlab.ui.control.Label
        SelectdatalocationDropDownLabel  matlab.ui.control.Label
        SelectDataLocationDropDown    matlab.ui.control.DropDown
        InfoIcon_2                    matlab.ui.control.Image
        SchemaTab                     matlab.ui.container.Tab
        SelectSessionSchemaLabel      matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        SchemaPanel                   matlab.ui.container.Panel
        SelectSchemaTemplateLabel     matlab.ui.control.Label
        SelectSchemaTemplateDropDown  matlab.ui.control.DropDown
        SchemaHelpImageButton         matlab.ui.control.Image
        MetadataTab                   matlab.ui.container.Tab
        MetadataHeading               matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        MetadataPanel                 matlab.ui.container.Panel
        ShowAdvancedOptionsButton_2   matlab.ui.control.StateButton
        VariablesTab                  matlab.ui.container.Tab
        DataVariablesLabel            matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        VariableEditorPanel           matlab.ui.container.Panel
        Label_16                      matlab.ui.control.Label
        AddVariablesButton            matlab.ui.control.Button
        ShowPresetVariablesButton     matlab.ui.control.StateButton
        AddonsAdvancedTab             matlab.ui.container.Tab
        AddonsPanelAdvanced           matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        SaveMatlabPathButton          matlab.ui.control.Button
        DownloadToolboxesLabel        matlab.ui.control.Label
        DownloadSelectedButton        matlab.ui.control.Button
        DownloadAllButton             matlab.ui.control.Button
        ToolboxTab                    matlab.ui.container.Tab
        Label_11                      matlab.ui.control.Label
        InstallToolboxesLabel         matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Image5                        matlab.ui.control.Image
        AddonInstallationNote         matlab.ui.control.Label
        SkipButton                    matlab.ui.control.Button
        AddonInstructionLabel         matlab.ui.control.Label
        HelpAddonManager              matlab.ui.control.Image
        NavigationPanel               matlab.ui.container.Panel
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        NansenLogoImage               matlab.ui.control.Image
        Image6                        matlab.ui.control.Image
        Image7                        matlab.ui.control.Image
    end


    % Color & Appearance Properties
    properties
        
        FontName = 'Segoe UI';
        
        % Eksempel for hex definisjon:
        % GenericButtonFontColor = hex2rgb('000000');
        
        % Standard button
        GenericButtonFontColor = [0,0,0];
        GenericButtonBackgroundColor = [0.9,0.9,0.9];
        
        % Install button
        InstallButtonFontColor = [0.8784 0.8784 0.7059];
        InstallButtonBackgroundColor = [0 0.6 0];
        
        % Dropdown menu
        DropdownMenuBackgroundColor = [1,1,1];
        
    end


    properties (Access = private) % Page Data models & UI Interfaces
        
        ProjectManager % Interface for working with projects
        
        AddonManager   % Interface for installing addons/toolboxes
        AddonUiTable   % UI Table for displaying list of supported addons
        
        DataLocationModel
        DLEditor
        
        FolderLevelList struct

        % Folder Organization Editor
        FOEditor % Note: changes go into the DataLocation Model
        
        % Metadata Variable Editor
        MetaDataTable % Note: changes go into the DataLocation Model
        
        % Variable Editor
        FPSEditor % Rename to model
        FPSUiTable
        
        projectRootFolder
        
                
        TabUpdateRequired = struct('IntroTab', false, 'AddonsTab', false, ...
            'ProjectTab', false, 'SchemaTab', false, ...
            'DataLocationsTab', true, 'FolderHierarchyTab', true, ...
            'MetadataTab', false, 'VariablesTab', false)
        
        DetectedFolderList
        DetectedFolderTable
        
        FolderOrganizationFilterListener
    end
    
    
    properties (Access = private) % App components that are created programmatically
        
        PageIndicator % Description
        NextButton2
        PreviousButton2
        
    end
    
    
    methods (Access = private) % Methods for creating pages
        
        function createPagesOnStartup(app)
            
            % Create table for addon/toolbox manager
            app.createAddonTable()
            
            rootdir = utility.path.getAncestorDir(nansen.rootpath, 1);
            projectFolder = fullfile(rootdir, '_userdata', 'projects');
            projectFolder = getpref('NansenSetup', 'DefaultProjectPath', projectFolder);
            app.projectRootFolder = projectFolder;
            
            % Create data location editor table on Data Locations Tab
            app.createDataLocationsTable()
            
            % Todo: create folder organization page
            app.createFolderOrganizationTable()
            
            % Todo: create variable configurator
            app.createVariableEditorPage()
            
            app.createMetaVariableEditorPage()
            

        end 
        
        function createToolboxPage(app, toolboxNames)
            
            xPos = 70;
            yPos = 220;
            
            editFieldHeight = 22;
            editFieldSpacing = 10;
            
            numToolboxes = numel(toolboxNames);
            
            for i = 1:numToolboxes
                number = uilabel(app.Panel);
                number.Text = sprintf('%d.', i);
                number.Position = [xPos-20, yPos, 22,22];
                
                nameField = uieditfield(app.Panel, 'text');
                nameField.Value = toolboxNames{i};
                nameField.Editable = false;

%                 nameField = uilabel(app.Panel);
%                 nameField.Text = toolboxNames{i};

                nameField.BackgroundColor = [0.9412 0.9412 0.9412];
                w = numel(toolboxNames{i}) * 12 * 0.55;
                nameField.Position = [xPos, yPos, w, editFieldHeight];
                
                yPos = yPos - editFieldHeight - editFieldSpacing;
            end

        end
        
        function createAddonTable(app)
        %createAddonTable Create a table of supported addons

            app.AddonManager = nansen.setup.model.Addons;
            
            % This will create the advanced options for addon installation
            hPanel = app.AddonsPanelAdvanced;
            args = {hPanel, app.AddonManager};
            app.AddonUiTable = nansen.setup.view.AddonTable(args{:});
            
            
            % This will create the default view for addon installation
            data = app.AddonManager.AddonList;
            isInstalled = [data.IsInstalled];
            
            names = {data(~isInstalled).Name}';
            descriptions = {data(~isInstalled).Description}';
            isRequired = [data(~isInstalled).IsRequired]';
            
            requiredLabel = cell(size(names));
            requiredLabel(isRequired) = {'Required'};
            requiredLabel(~isRequired) = {'Optional'};
            
            if isempty(names)
                app.UITable.Visible = 'off';
                % Todo: Just skip page altogether
                app.Label_13.Text = 'All packages are installed. You can proceed';
            else
                app.UITable.Data = [names, descriptions, requiredLabel];
            end
            
            % app.UITable.Data = [names, isRequired];
            
            
        end

        function createDataLocationsTable(app)
        
            hPanel = app.DataLocationPanel;
            
            % Load data location model
            %filePath = nansen.ProjectManager.getFilePath('DataLocationSettings');
            app.DataLocationModel = nansen.setup.model.DataLocations();%(filePath);
            
            % Todo: set path 
            
            % Get data location configurations (Initialize if necessary)
            if isempty(app.DataLocationModel.Data(1).Name)
                S = nansen.setup.defaults.dataLocation;
                app.DataLocationModel.Data = S;
            else
                S = app.DataLocationModel.Data;
            end
            
            if ~isempty(app.DLEditor)
                delete(app.DLEditor)
            end
            
            % Create a a ui interface for editing the configurations
            args = {'Parent', hPanel, 'Data', S};
            app.DLEditor = nansen.setup.view.DataLocationEditor(args{:});


        end
        
        function createFolderOrganizationTable(app)

            hPanel = app.FolderOrganizationInputPanel;
            
            % Todo: Get from data location model
            data = app.DataLocationModel.Data(1).SubfolderStructure();
            
            nvPairs = {'Parent', hPanel, 'Data', data, 'DataLocation', app.DataLocationModel.Data(1)};
            app.FOEditor = nansen.setup.view.FolderOrganizationEditor(nvPairs{:});
            app.FOEditor.hideAdvancedOptions()
            
        end
        
        function createVariableEditorPage(app)
            
            app.FPSEditor = nansen.setup.model.FilePathSettingsEditor;
            app.FPSUiTable = nansen.setup.view.VariableEditorTable(app.VariableEditorPanel, ...
                'Data', app.FPSEditor.VariableList, 'DataLocationModel', app.DataLocationModel);
                
            app.FPSUiTable.hidePresetVariables()
            app.ShowPresetVariablesButton.Text = 'Show all variables...';
            app.AddVariablesButton.Enable = 'off';
            
            
        end
        
        function createMetaVariableEditorPage(app)
            
            S = app.DataLocationModel.Data(1).MetaDataDef();
            app.MetaDataTable = nansen.setup.view.MetadataDefinitionTable(app.MetadataPanel, 'Data', S);
            app.MetaDataTable.hideAdvancedOptions()
            app.updateMetadataTab()

        end
        
% % % % Methods for the folder listing figure and table
        
        function createFolderListingView(app)
           
            % Todo: Move figure to left side of current screen:
            %app.NansenSetupUIFigure.Position(1) = 10;
            
            hAppFig = app.NansenSetupUIFigure;
            [hFig, hTable] = nansen.setup.window.createFolderListUiTable(hAppFig);
            
            app.DetectedFolderList = hFig;
            app.DetectedFolderTable = hTable;
            
            hFig.DeleteFcn = @(s,e) app.closeFolderListingView;
            
            app.FolderOrganizationFilterListener = listener(app.FOEditor, ...
                'FilterChanged', @app.refreshTableData);
            
            % Give focus to the setup figure
            figure(app.NansenSetupUIFigure)
            
        end
    
        function showFolderListingView(app)
        
            app.NansenSetupUIFigure.Position(1) = 10;

            if isempty(app.DetectedFolderList) || ~isvalid(app.DetectedFolderList)
                                
                app.createFolderListingView()
                app.refreshTableData()
                
            end
                   
            app.PreviewFolderListImage.ImageSource = 'look3.png';
            app.DetectedFolderList.Visible = 'on';
            pause(0.01)
            figure(app.NansenSetupUIFigure)
            
        end
                
        function hideFolderListingView(app)
            
            if isvalid(app.DetectedFolderList)
                app.PreviewFolderListImage.ImageSource = 'look2.png';
                app.DetectedFolderList.Visible = 'off';
                uim.utility.centerFigureOnScreen(app.NansenSetupUIFigure)
            end
            
        end
        
        function closeFolderListingView(app)
            app.PreviewFolderListImage.ImageSource = 'look2.png';
            uim.utility.centerFigureOnScreen(app.NansenSetupUIFigure)
        end

        
    end
    
    methods (Access = private) % Methods for updating pages
    
        function isCompleted = checkIfTabCompleted(app, tabName)
            
            isCompleted = true;
            
            switch tabName
                case 'Project'
                    return
                    if isempty(app.ProjectLabelEditField.Value)
                        msg = 'You need to enter a project name before proceeding';
                        title = 'Project name missing';
                        isCompleted = false;
                    else
                        if strcmp(app.CreateNewProjectButton.Enable, 'on')
                            selection = uiconfirm(app.NansenSetupUIFigure, 'Create project now?', 'Create Project', 'Icon', 'hjelp2.png');
                            switch selection
                                case 'Ok'
                                    % Todo: need confirmation that this
                                    % worked
                                    app.CreateNewProjectButtonValueChanged()
                                    % Todo: Give feedback to user that ne
                                    % project was created.
                                case 'Cancel'
                                    msg = '';
                                    isCompleted = false;
                            end
                        end


                    end
                    
                case 'Data Locations'
                    if app.DLEditor.isTypeMissing()
                        msg = 'One or more data location types are not filled in';
                        title = 'Data location type is missing';
                        isCompleted = false;
                    elseif app.DLEditor.isPathMissing()
                        msg = 'One or more data location root directories are not filled in';
                        title = 'Data location root directory is missing';
                        isCompleted = false;
                    end
            end
            
            
            if ~isCompleted && ~isempty(msg)
                uialert(app.NansenSetupUIFigure, msg, title)
            end
            
        end
        
        function checkTabForChanges(app, tabName)
        %checkTabForChanges Check for changes on interdependent tabs
        %
        %   If there has been changes to the data model on a given tab, a
        %   dependent tab is flagged for update and the datamodel is
        %   updated.
            
            switch tabName
                
                case 'Addons'
                    if app.ShowAdvancedOptionsButton.Value

                        if app.AddonManager.IsDirty
                            message = 'Do you want to add downloaded toolboxes to the matlab path?';
                            title = 'Update Matlab''s savepath?';
                
                            selection = uiconfirm(app.NansenSetupUIFigure, message, ...
                                title, 'Options',{'Yes','Skip'},...
                                'DefaultOption',1,'CancelOption',2);
                            
                            if strcmp(selection, 'Yes')
                                savepath
                                app.AddonManager.markClean()
                                app.AddonManager.restoreAddToPathOnInitFlags()
                            end
                        end
                        
                    else
                        % By default, we should install all toolboxes
                        app.DownloadAllButtonPushed([])
                        savepath
                        app.AddonManager.markClean()
                        app.AddonManager.restoreAddToPathOnInitFlags()
                    end
                    
                case 'Data Locations'
                    app.DataLocationModel.Data = app.DLEditor.Data;
                    
                    if app.DLEditor.isNameDirty || app.DLEditor.isPathDirty || app.DLEditor.isDirty
                        app.TabUpdateRequired.FolderHierarchyTab = true;
                        % Todo: Will this work?
                    end
                    
% %                     if app.DLEditor.isNameDirty
% %                         app.TabUpdateRequired.FolderHierarchyTab = true;
% %                         for i = 1:numel(app.DataLocationModel.Data)
% %                             app.DataLocationModel.Data(i).Name = ...
% %                                 app.DLEditor.Data(i).Name;
% %                         end
% %                         
% %                         % Todo: Will this work?
% %                         app.DataLocationModel.Data = app.DLEditor.Data;
% %                         
% %                     end
% %                     
% %                     if app.DLEditor.isPathDirty
% %                         app.TabUpdateRequired.FolderHierarchyTab = true;
% %                         for i = 1:numel(app.DataLocationModel.Data)
% %                             app.DataLocationModel.Data(i).RootPath = ...
% %                                 app.DLEditor.Data(i).RootPath;
% %                         end   
% % 
% %                     end
                    
                    
                    app.FPSUiTable.setDataLocationModel(app.DataLocationModel)
                    app.DLEditor.markClean()
                    
                case 'Folder Organization'
                    
                    if app.FOEditor.IsDirty
                        app.TabUpdateRequired.MetadataTab = true;
                        app.TabUpdateRequired.VariablesTab = true;

                        % Get data location index from dropdown selector
                        currentDataLoc = app.SelectDataLocationDropDown.Value;
                        dataLocInd = strcmp({app.DataLocationModel.Data.Name}, currentDataLoc);


                        S = app.FOEditor.getSubfolderStructure();
                        app.DataLocationModel.updateSubfolderStructure(S, find(dataLocInd))
                        app.FOEditor.markClean()
                        
                        app.FPSUiTable.setDataLocationModel(app.DataLocationModel)
                    end
                    
                    app.hideFolderListingView()
                    
                    
                case 'Metadata'
                    
                    if app.MetaDataTable.IsDirty
                        S = app.MetaDataTable.getMetaDataDefinitionStruct();
                        app.DataLocationModel.updateMetaDataDefinitions(S)
                    end
                    
                    
            end
            
            
        end
        
        function updateFolderOrganizationTab(app)
            
            if ~app.TabUpdateRequired.FolderHierarchyTab
                return
            end
            
            app.updateDataLocationButtons()
            
            % Todo: Only update if the current data location was changed....
            
            %Select DataLocations Number based on button group.
            currentDataLoc = app.SelectDataLocationDropDown.Value;
            dataLocInd = strcmp({app.DataLocationModel.Data.Name}, currentDataLoc);
            
            % Important: Delete this listener so that the the table is not 
            % updated while recreating the subfolder settings table.            
            app.FOEditor.DataLocation = app.DataLocationModel.Data(dataLocInd);
            
            app.TabUpdateRequired.FolderHierarchyTab = false;
            
        end
        
        function updateMetadataTab(app)
            
            app.MetaDataTable.DataLocations = app.DataLocationModel;
            app.MetaDataTable.onModelSet()
            
        end
        
        function refreshTableData(app, src, evt)
            
            % Todo: Make external function
            
            currentDataLoc = app.SelectDataLocationDropDown.Value;

            dataLocInd = strcmp({app.DataLocationModel.Data.Name}, currentDataLoc);
            dataLocInd = find(dataLocInd);
            
            
            S = app.FOEditor.getSubfolderStructure;
            app.DataLocationModel.updateSubfolderStructure(S, dataLocInd)
          
            rootPath = app.DataLocationModel.Data(dataLocInd).RootPath{1};
            
            sessionFolders = nansen.dataio.session.listSessionFolders(app.DataLocationModel, currentDataLoc);
            if isempty(sessionFolders); sessionFolders = {''}; end

            sessionFolders = sessionFolders.(currentDataLoc);
            sessionFolders = strrep(sessionFolders, rootPath, '...');
           
            app.DetectedFolderTable.Data = sessionFolders';

        end
        
    end
        
    methods
        
        function onChangePagePressed(app, direction)
        %onChangePagePressed Callback for clicks on next/previous buttons
            
            switch direction
                case 'previous'
                    incr = -1;
                case 'next'
                    incr = 1;
            end
            
            % Get the current tab.
            currentTab = app.TabGroup.SelectedTab;
            
            % Find tab number of current tab and new tab
            currentTabNum = find(ismember(app.TabGroup.Children, currentTab));
            newTabNum = currentTabNum + incr;
            
            % Don't switch to tabs outside range
            if newTabNum < 1 || newTabNum > numel(app.TabGroup.Children)
                return
            end
            
            app.changeTab(currentTabNum, newTabNum)
            
        end
        
        function onPageSwitcherPressed(app, src, evt)
            app.changeTab(evt.OldPageNumber, evt.NewPageNumber)
        end
        
        function changeTab(app, currentTabNum, newTabNum)
        %changeTab Change tabs and do all the internal work needed.
        
            % Todo: Use tab numbers or tab titles as inputs?
            
            currentTab = app.TabGroup.Children(currentTabNum);
            newTab = app.TabGroup.Children(newTabNum);
            
            % Check if tab is completed and we can move on
            isCompleted = app.checkIfTabCompleted(currentTab.Title);
            if ~isCompleted
                return
            end
            
            app.checkTabForChanges(currentTab.Title)
            
            % Change the selected tab.
            app.TabGroup.SelectedTab = newTab;
            
            % Call the function to apply changes needed when moving to a
            % new tab.
            app.TabGroupSelectionChanged()
            
            app.PageIndicator.changePage(newTabNum)
            
            % Turn off navigation bar if going back to Welcome page 
            if newTabNum == 1
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'off';
                app.PreviousButton2.Visible = 'off';
            else
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'on';
                app.PreviousButton2.Visible = 'on';
            end
            
% %             if newTabNum == 8
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.finish;
% %             else
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.next2;
% %             end
            
        end
        
        function S = getCurrentDataLocation(app)
        %getCurrentDataLocation Get currently selected data location
           
            currentDataLoc = app.SelectDataLocationDropDown.Value;

            dataLocInd = strcmp({app.DataLocationModel.Data.Name}, currentDataLoc);
            
            S = app.DataLocationModel.Data(dataLocInd);
        end
        
        function updateDataLocationButtons(app)
        %updateDataLocationButtons Update buttons for switching between 
        % data locations on the folder organization tab.
        
            buttonWidth = 100;
            
            numDataLocs = numel(app.DataLocationModel.Data);
            numButtons = numel(app.SelectDataLocationDropDown.Items);


            for i = 1:numDataLocs
            
                newName = app.DataLocationModel.Data(i).Name;
                
                % Update name of togglebuttons in togglebutton group on
                % FolderHierarchyTab
            
                % Add new, or rename button
                if i > numButtons
                    app.SelectDataLocationDropDown.Items{end+1} = newName;
                else
                    app.SelectDataLocationDropDown.Items{i} = newName;
                end
                
            end
            
            % Remove buttons if there are too many (i.e data locations were removed)
            if numButtons > numDataLocs
                
                for i = numButtons : -1 : numDataLocs+1
                    app.SelectDataLocationDropDown.Items(i) = [];
                end
            end
            
        end
        
        function finishSetup(app)
        %finishSetup Finish the setup based on user configurations
        
            % Todo: Save the Data Location model and the filepath settings.
            
            % Todo: Make external function
                        
% % % % % % Save changes from data location and folder organization pages. 
            % Save the data location model
            app.DataLocationModel.refreshFilePath()
            app.DataLocationModel.save()
            
            % Set data location model to global
            global dataLocationModel
            dataLocationModel = app.DataLocationModel;

% % % % % % Save changes from data variables page 
            S = app.FPSUiTable.getUpdatedTableData();
            app.FPSEditor.setVariableList(S)
            app.FPSEditor.save()
            
            global dataFilePathModel
            dataFilePathModel = nansen.setup.model.FilePathSettingsEditor;
            
            
          % % Use the folder structure to detect session folders.
            dataLocType = app.DataLocationModel.Data(1).Name; % select first. todo: generalize
            sessionFolders = nansen.dataio.session.listSessionFolders(app.DataLocationModel, dataLocType);
            
            
            % Convert to struct array. Todo: Make it work for multiple data
            % location types.
            sessionFolders = cell2struct(sessionFolders.(dataLocType), dataLocType, 1);
            
            % Todo: Create method for matching session folders from
            % different data location types.
            %dataLocations = app.DataLocationModel.listSessionFolders();
            
            if isempty(sessionFolders)
                title = 'No session folders were found';
                msg = 'No session folders were found';
                
                uialert(app.NansenSetupUIFigure, msg, title)
                return
            end
            
          % % Create and save a MetaTable for detected sessions in the 
            % current project
            
            % Todo: Get schema based on selection
            sessionSchema = @nansen.metadata.schema.vlab.TwoPhotonSession;
            
            
% % % % % % TODO: Make this into a function (Initialize meta table)
            % Create a list of session metadata objects
           
            numSessions = numel(sessionFolders);
            sessionArray = cell(numSessions, 1);
            for i = 1:numSessions
                sessionArray{i} = sessionSchema(sessionFolders(i));
            end
            sessionArray = cat(1, sessionArray{:});

            % Initialize a MetaTable using the given session schema and the
            % detected session folders.
            metaTable = nansen.metadata.MetaTable.new(sessionArray);
            
            % Add default information for saving the metatable to a struct
            S = struct();
            S.MetaTableName = metaTable.createDefaultName;
            S.SavePath = nansen.ProjectManager.getProjectSubPath('MetaTable');
            S.IsDefault = true;
            S.IsMaster = true;
            
            % Save the metatable in the current project
            try
                metaTable.archive(S);
            catch ME
                % Todo: have some error handling here.
% %                 title = 'Could not save metadata table';
% %                 uialert(app.NansenSetupUIFigure, ME.message, title) 
            end

            %close(app.NansenSetupUIFigure)

            nansen
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPanel)
            
            hFig = app.NansenSetupUIFigure; % Just to shorten variablename
            uim.utility.centerFigureOnScreen(hFig)
            
            S = nansen.theme.getThemeColors('deepblue');


            app.NavigationPanel.BackgroundColor = S.HeaderBgColor;

            % Find and set background colors of tabs
            hTabs = app.TabGroup.Children;
            set(hTabs, 'BackgroundColor', S.FigureBgColor)

            % Find handles for all page title labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageTitleLabel'};
            hPageTitleLabels = findobj(hFig, tokens{:});
            set(hPageTitleLabels, 'BackgroundColor', S.HeaderBgColor, 'FontColor', S.HeaderFgColor, 'Position', [1,511,786,78], 'VerticalAlignment', 'bottom')
            
            % Find handles for all page description labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageDescriptionLabel'};
            hPageDescriptionLabels = findobj(hFig, tokens{:});
            set(hPageDescriptionLabels, 'BackgroundColor', 'None')

            app.Image6.Position(1:2) = [1,1];
            
            app.Label_1.Interpreter = 'html';
            
            app.StartButton.Text = 'Preparing...';
            app.StartButton.Enable = 'off';
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
            drawnow
            
            % Create pages
            app.createPagesOnStartup()
            
            
            % Set some button icons.
            iconPath = fullfile(matlabroot, 'toolbox', 'shared', 'controllib', 'general', 'resources', 'toolstrip_icons', 'Set_Path_24.png');
            app.SaveMatlabPathButton.Icon = iconPath;
            
            iconPath = fullfile(matlabroot, 'toolbox', 'shared', 'controllib', 'general', 'resources', 'toolstrip_icons', 'Import_24.png');
            app.DownloadSelectedButton.Icon = iconPath;
            app.DownloadAllButton.Icon = iconPath;
            
            
            % Customize appeareance of buttons and controls
            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uibutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)

            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uistatebutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)
            
            hDropdown = findobj(app.NansenSetupUIFigure, 'type', 'uidropdown');
            set(hDropdown, 'BackgroundColor', app.DropdownMenuBackgroundColor)
            set(hDropdown, 'FontName', app.FontName)
            
            specialButtons = [app.SaveMatlabPathButton,app.DownloadSelectedButton,app.DownloadAllButton];
            set(specialButtons, 'FontColor', [0.15, 0.15, 0.15])
            set(specialButtons, 'BackgroundColor', 'w')
            
            
            uicc = uim.UIComponentCanvas(app.NavigationPanel);

            %obj.h.ButtonPrevious = uim.control.Button(uicc, 'String', 'Previous', 'Size', buttonSize, 'Location', 'southwest', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [180, 70, 0, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('previous'));
            %obj.h.ButtonNext = uim.control.Button(uicc, 'String', 'Next', 'Size', buttonSize, 'Location', 'southeast', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [0, 70, 180, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('next'));
            
            % Create page indicator
            options = {'Size', [inf, 60], 'Margin', [13, 13, 20, 20], ...
                'Location', 'southwest', 'IndicatorSize', 10, ...
                'SizeMode', 'manual', 'BarColor', S.FigureBgColor, ...
                'FontColor', S.FigureBgColor, 'IndicatorColor', S.HeaderMidColor, ...
                'FontSize', 14, 'IndicatorSize', 12};

            pageNames = {app.TabGroup.Children(1:end-2).Title};
            app.PageIndicator = uim.widget.PageIndicator(uicc, pageNames, options{:});
            app.PageIndicator.ChangePageFcn = @app.onPageSwitcherPressed;
            
            
            ICONS = uim.style.iconSet(applify.apptable1.getIconPath);

          
            app.PreviousButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.prev2, 'Position', [285,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [10,10,10,10], 'MechanicalAction', 'Latch when released', ...
                'Callback', @(src,evt) app.PreviousButtonPushed(evt), ...
                'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
            
            app.NextButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.next2, 'Position', [425,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [16,10,10,10], 'MechanicalAction', 'Latch when released',...
                'Callback', @(src,evt) app.NextButtonPushed(evt), ...
                 'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
                %'Style', uim.style.buttonSymbol, ...
               
            
% %             btnBgColor = ones(1,3) .* [0    0.3020    0.4980];
% %             btnFgColor = ones(1,3) .* 0.98;
% %             
% %              
% %             app.NextButton.BackgroundColor = btnBgColor;
% %             app.PreviousButton.BackgroundColor = btnBgColor;
% %             app.NextButton.FontColor = btnFgColor;
% %             app.PreviousButton.FontColor = btnFgColor;
            
            drawnow

            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = '';
            
            app.StartButton.Text = 'Let''s go!';
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
            
% %             app.StartButton_3.BackgroundColor = S.HeaderBgColor;
% %             app.StartButton_3.FontColor = S.HeaderFgColor;
% %             app.StartButton_2.BackgroundColor = S.HeaderBgColor;
% %             app.StartButton_2.FontColor = S.HeaderFgColor;
% %             app.StartButton_2.Visible = 'on';
% %             app.StartButton_3.Visible = 'on';

%             lamps = [app.Lamp, app.Lamp_2, app.Lamp_3, app.Lamp_4, app.Lamp_5];
%             
%             for i = 1:5
%                 lamps(i).Color = bgColor;
%                 drawnow
%                 pause(1)
%             end
            
        end

        % Button pushed function: StartButton, StartButton_2, 
        % StartButton_3
        function StartButtonPushed(app, event)
            
            requiredToolboxes = {...
                'Statistics and Machine Learning Toolbox', ...
                'Image Processing Toolbox', ...
                'Parallel Computing Toolbox' ...
                }; 
            
            TF = nansen.setup.isToolboxInstalled(requiredToolboxes);
            %TF = false(1,3);
            if ~ all(TF)
                gotoNum = contains({app.TabGroup.Children.Title}, 'Toolbox');
                app.createToolboxPage(requiredToolboxes(~TF))
                app.TabGroup.SelectedTab = app.TabGroup.Children(gotoNum);
                app.PageIndicator.Visible = 'off';
                return
            end
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            app.PageIndicator.changePage(2)
            
            %app.NavigationPanel.Visible = 'on';
            app.NextButton2.Visible = 'on';
            app.PreviousButton2.Visible = 'on';


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            % Check if this is the last tab and we should finish.
            if strcmp(app.TabGroup.SelectedTab.Title, 'Variables')
                app.finishSetup()
                return
            end
            
            app.onChangePagePressed('next')
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.onChangePagePressed('previous')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        %TabGroupSelectionChanged Do things when tab is selected.
            
            switch app.TabGroup.SelectedTab.Title
                case 'Data Locations'
                    app.DLEditor.Data = app.DataLocationModel.Data;
                    if app.TabUpdateRequired.DataLocationsTab
                        app.TabUpdateRequired.DataLocationsTab = false;
                    end
                    
                case 'Folder Organization'
                    app.updateFolderOrganizationTab()
                    if isempty(app.DetectedFolderList)
                        app.showFolderListingView()
                    end
                    
                case 'Metadata'
                    if app.TabUpdateRequired.MetadataTab
                        app.updateMetadataTab()
                    end
                    
                case 'Variables'
                    if app.TabUpdateRequired.VariablesTab
                        %app.updateVariablesTab()
                        app.TabUpdateRequired.VariablesTab = false;
                    end

            end
            
        end

        % Button pushed function: CreateNewProjectButton
        function CreateNewProjectButtonValueChanged(app, event)

            
            projectLongName = app.ProjectNameEditField.Value;
            projectShortName = app.ProjectLabelEditField.Value;
            projectFolderPath = app.LocalPathEditField.Value;
            
            isValidProjectName = isvarname(projectShortName);
            
            
            if ~isValidProjectName
                if isempty(projectShortName)
                    message = 'Please enter a name for the project';
                    title = 'Project Name Missing';

                else
                    message = 'Project short name can only consist of letters, numbers and underscores';
                    title = 'Invalid Project Name';
                end
                uialert(app.NansenSetupUIFigure, message, title)
                return
            end
    
% %             if isempty(projectLongName)
% %                 message = 'Please enter a name for project';
% %                 title = 'Project Name Missing';
% %                 
% %                 % app.displayMessage(message, true)
% %                 uialert(app.NansenSetupUIFigure, message, title)
% %                 return
% %                 
% %             end

            if isempty(projectFolderPath)
                message = 'Please enter a local path for saving project metadata';
                title = 'Project Path Missing';
                
                uialert(app.NansenSetupUIFigure, message, title)

                return
            end


            if strcmp(app.CreateNewProjectButton.Text, 'Create New Project')
                
                % Todo: Check that project does not exist...
                                
                % Create new project
                args = {projectShortName, projectLongName, projectFolderPath};
                
                try
                    nansen.setup.createProject(args{:})
                catch ME
                    title = 'Something went wrong';
                    uialert(app.NansenSetupUIFigure, ME.message, title)
                    return
                end
                
                % Set filepath for datalocation model based on this project
                filePath = nansen.localpath('DataLocationSettings');
                %app.DataLocationModel.setFilePath(filePath)
                app.DataLocationModel.refresh()
                app.createDataLocationsTable()
                
                
                % Set filepath for variable file path settings
                filePath = nansen.localpath('FilePathSettings');
                app.FPSEditor.refresh()
                
                app.TabUpdateRequired.DataLocationsTab = true;
                app.TabUpdateRequired.FolderHierarchyTab = true;
                app.TabUpdateRequired.MetadataTab = true;
                app.TabUpdateRequired.VariablesTab = true;

                % Disable all input fields.
                app.ProjectNameEditField.Enable = 'off';
                app.ProjectLabelEditField.Enable = 'off';
                app.LocalPathEditField.Enable = 'off';
                app.ChangeProjectFolderButton.Enable = 'off';
                
                % Change appearance of "create project" button
                app.CreateNewProjectButton.Text = 'Project Created!';
                app.CreateNewProjectButton.BackgroundColor = [0.47,0.87,0.19];
                app.CreateNewProjectButton.Enable = 'off';
            end
        end

        % Button pushed function: ChangeProjectFolderButton
        function ChangeProjectFolderButtonPushed(app, event)
            
            % Get variables from UIControls
            currentFolder = fileparts(app.LocalPathEditField.Value);
            projectShortName = app.ProjectLabelEditField.Value;

            % Call function thats asks user to interactively select a new folder
            newFolder = nansen.setup.changeProjectFolder(currentFolder, projectShortName);

            % Bring ui figure back into focus.
            figure(app.NansenSetupUIFigure)
            
            % Return if user canceled or update the folder ui control
            if newFolder == 0
                return; 
            else
                app.LocalPathEditField.Value = newFolder;
            end
            
            % Todo: Set current project folder
            app.projectRootFolder = getpref('NansenSetup', 'DefaultProjectPath');

        end

        % Value changed function: ProjectLabelEditField
        function ProjectLabelEditFieldValueChanged(app, event)
            
            projectOldName = event.PreviousValue;
            
            % Get the project name and current path
            projectNewName = app.ProjectLabelEditField.Value;
            currentProjectPath = app.LocalPathEditField.Value;
            
            % Make sure its a valid name (letters/numbers/underscore only)
            isNameValid = isvarname(projectNewName);
            
            if isempty(projectNewName) || isNameValid
                return 
                
            elseif ~isNameValid
                msg = 'Project name can only consist of letters, numbers and underscores';
                uialert(app.NansenSetupUIFigure, msg, 'Invalid input')
                %app.displayMessage(msg, true)
                app.ProjectLabelEditField.Value = '';
                projectOldName = projectNewName;
                projectNewName = '';
            end
            
            % Update/create the project path based on new name selection
            projectFolder = nansen.setup.autoUpdateProjectPath(...
                projectNewName, projectOldName, currentProjectPath);
            
            % Update the value of the local path field
            app.LocalPathEditField.Value = projectFolder;
            
            %app.projectRootFolder = getpref('NansenSetup', 'DefaultProjectPath');

            
        end

        % Value changing function: ProjectLabelEditField
        function ProjectLabelEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            app.LocalPathEditField.Value = fullfile(app.projectRootFolder, changingValue);
            app.LocalPathEditField.Tooltip = app.LocalPathEditField.Value;
        end

        % Button pushed function: AddDataLocationButton
        function AddDataLocationButtonPushed(app, event)
            
%             numRows = numel(app.DataLocationodel.Data);
%             if isempty(app.DLEditor.Data(numRows).Name)
%                 return
%             end
            numRows = app.DLEditor.NumRows;
            rowData = app.DataLocationModel.getEmptyObject;
            %app.DataLocationModel.insertObject(rowData)
            app.DLEditor.addRow(numRows+1, rowData)
        end

        % Value changed function: SelectDataLocationDropDown
        function DataLocationSelectionChanged(app, event)
            
            % Important: Delete this listener so that the the table is not 
            % updated while recreating the subfolder settings table.
            delete(app.FolderOrganizationFilterListener)
            
            oldDataLoc = event.PreviousValue;
            newDataLoc = event.Value;
            oldInd = strcmp({app.DataLocationModel.Data.Name}, oldDataLoc);
            newInd = strcmp({app.DataLocationModel.Data.Name}, newDataLoc);
            
            % Get data and save in datalocationmodel
            S = app.FOEditor.getSubfolderStructure();
            app.DataLocationModel.updateSubfolderStructure(S, find(oldInd))
            
            app.FOEditor.DataLocation = app.DataLocationModel.Data(newInd);
            
            drawnow
            
            app.refreshTableData()
            
            % Important: Restore listener.
            app.FolderOrganizationFilterListener = listener(app.FOEditor, 'FilterChanged', @app.refreshTableData);


        end

        % Image clicked function: PreviewFolderListImage
        function ViewFolderListingButtonPushed(app, event)

            % Todo: Create methods for code blocks below.
            
            if ~isempty(app.DetectedFolderList) && isvalid(app.DetectedFolderList)
                
                if strcmp(app.DetectedFolderList.Visible, 'on')
                    app.hideFolderListingView()
                elseif strcmp(app.DetectedFolderList.Visible, 'off')
                    app.showFolderListingView()
                end
                
                return
                
            else
                app.showFolderListingView()
            end


        end

        % Button pushed function: DownloadAllButton
        function DownloadAllButtonPushed(app, event)

            for i = 1:numel(app.AddonManager.AddonList)
                S = app.AddonManager.AddonList(i);
                if ~S.IsInstalled
                    % Call the Addon's Uitable install button callback
                    app.AddonUiTable.onInstallAddonPushed(S.Name, i)
                end
            end
        end

        % Button pushed function: DownloadSelectedButton
        function DownloadSelectedButtonPushed(app, event)
            
            for i = 1:numel(app.AddonManager.AddonList)
                S = app.AddonManager.AddonList(i);
                if ~S.IsInstalled && ismember(i, app.AddonUiTable.SelectedRows)
                    % Call the Addon's Uitable install button callback
                    app.AddonUiTable.onInstallAddonPushed(S.Name, i)
                end
            end
        end

        % Button pushed function: SaveMatlabPathButton
        function SaveMatlabPathButtonPushed(app, event)
                        
            message = 'This will permanently add the downloaded addons to the MATLAB search path.';
            title = 'Confirm Save';
            
            selection = uiconfirm(app.NansenSetupUIFigure, message, ...
                title, 'Options',{'Save Path','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            
            if strcmp(selection, 'Cancel'); return; end
            
            savepath
            
            app.AddonManager.markClean()
            app.AddonManager.restoreAddToPathOnInitFlags()
            
        end

        % Callback function
        function SchemaTabButtonsSelectionChanged(app, event)
            selectedButton = app.SchemaTabButtons.SelectedObject;
            
            switch selectedButton
                case app.SchemaTab3Button
                    app.SchemaTabGroup.SelectedTab = app.SchemaTabGroup.Children(3);
                case app.SchemaTab2Button
                    app.SchemaTabGroup.SelectedTab = app.SchemaTabGroup.Children(2);
                case app.SchemaTab1Button
                    app.SchemaTabGroup.SelectedTab = app.SchemaTabGroup.Children(1);
            end
            
            
        end

        % Callback function
        function CreateSchemaButtonPushed(app, event)
            
            msg = 'This feature is not implemented yet';
            title = 'Try again later';
            uialert(app.NansenSetupUIFigure, msg, title, 'Icon', 'info')
            
        end

        % Image clicked function: HelpAddonManager
        function HelpAddonManagerClicked(app, event)
            app.Image5.Visible = 'on';
        end

        % Image clicked function: SchemaHelpImageButton
        function SchemaHelpImageButtonClicked(app, event)
            
            schemaName = app.SelectSchemaTemplateDropDown.Value;
            msg = nansen.setup.getSchemaDefinition(schemaName);
                            
            hFigure = app.NansenSetupUIFigure;
            title = sprintf('Schema Definition');
            uialert(hFigure, msg, title, 'Icon', 'info2.png')
                
        end

        % Value changed function: ShowFilterOptionsButton
        function ShowFilterOptionsButtonPushed(app, event)
            
            if event.Value
                app.FOEditor.showAdvancedOptions()
                app.ShowFilterOptionsButton.Text = 'Hide Filter Options...';
            else
                app.FOEditor.hideAdvancedOptions()
                app.ShowFilterOptionsButton.Text = 'Show Filter Options...';
            end
        end

        % Value changed function: ShowAdvancedOptionsButton
        function ShowAdvancedAddonOptionsButtonPushed(app, event)
            
            if event.Value
                text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
                app.AddonsPanel.Visible = 'off';
                parentTab = app.AddonsTab;
                app.ShowAdvancedOptionsButton.Text = 'Hide Advanced Options...';
            else
                text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';
                app.AddonsPanel.Visible = 'on';
                parentTab = app.AddonsAdvancedTab;
                app.ShowAdvancedOptionsButton.Text = 'Show Advanced Options...';
            end
            
            app.Label_13.Text = text;
            
            % Move advanced options to current tab. Easier to have
            % components on different tabs during development.
            app.DownloadSelectedButton.Parent = parentTab;
            app.DownloadAllButton.Parent = parentTab;
            app.SaveMatlabPathButton.Parent = parentTab;
            app.AddonsPanelAdvanced.Parent = parentTab;
        

            %newTabNum = contains({app.TabGroup.Children.Title}, 'Addons (Advanced)');
            %app.TabGroup.SelectedTab = app.TabGroup.Children(newTabNum);

        end

        % Value changed function: ShowAdvancedOptionsButton_2
        function ShowAdvancedOptionsButton_2ValueChanged(app, event)
                       
            if event.Value
                app.MetaDataTable.showAdvancedOptions()
                app.ShowAdvancedOptionsButton_2.Text = 'Hide Advanced Options...';
            else
                app.MetaDataTable.hideAdvancedOptions()
                app.ShowAdvancedOptionsButton_2.Text = 'Show Advanced Options...';
            end
            
            
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            %app.NavigationPanel.Visible = 'on';
            app.NextButton2.Visible = 'on';
            app.PreviousButton2.Visible = 'on';
                
            app.PageIndicator.Visible = 'on';
            app.PageIndicator.changePage(2)
        end

        % Close request function: NansenSetupUIFigure
        function NansenSetupUIFigureCloseRequest(app, event)
            
            if ~isempty(app.DetectedFolderList) && isvalid(app.DetectedFolderList)
                delete(app.DetectedFolderList)
            end
           
            delete(app)

        end

        % Button pushed function: AddVariablesButton
        function AddVariablesButtonPushed(app, event)
            numRows = app.FPSUiTable.NumRows;
            rowData = app.FPSEditor.getEmptyObject;
            %app.DataLocationModel.insertObject(rowData)
            app.FPSUiTable.addRow(numRows+1, rowData)
        end

        % Value changed function: ShowPresetVariablesButton
        function ShowPresetVariablesButtonValueChanged(app, event)
            if event.Value
                app.FPSUiTable.showPresetVariables()
                app.ShowPresetVariablesButton.Text = 'Show crucial variables...';
                app.AddVariablesButton.Enable = 'on';
            else
                app.FPSUiTable.hidePresetVariables()
                app.ShowPresetVariablesButton.Text = 'Show all variables...';
                app.AddVariablesButton.Enable = 'off';
            end            
        end

        % Window button motion function: NansenSetupUIFigure
        function NansenSetupUIFigureWindowButtonMotion(app, event)

            
        end

        % Image clicked function: InfoIcon_2
        function InfoIcon_2ImageClicked(app, event)
            app.Image6.Visible = 'on';
            app.Image7.Visible = 'on';
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            app.Image6.Visible = 'off';
            app.Image7.Visible = 'off';

        end

        % Callback function
        function EyeIcon_2ImageClicked(app, event)
            switch app.Image8.Visible
                case 'on'
                    app.Image8.Visible = 'off';
                case 'off'
                    app.Image8.Visible = 'on';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NansenSetupUIFigure = uifigure('Visible', 'off');
            app.NansenSetupUIFigure.Name = 'Nansen Setup';
            app.NansenSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @NansenSetupUIFigureCloseRequest, true);
            app.NansenSetupUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @NansenSetupUIFigureWindowButtonMotion, true);
            app.TabGroup = uitabgroup(app.NansenSetupUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Label_1 = uilabel(app.IntroTab);
            app.Label_1.Tag = 'PageDescriptionLabel';
            app.Label_1.Text = {'This dialog will guide you through the initial configuration.'; ''; 'The aims are'; '    1) Install required toolboxes'; '    2) Detect your existing data folders'; '    3) Create a metadata table for your existing data.'};
            app.WelcomeLabel = uilabel(app.IntroTab);
            app.WelcomeLabel.Tag = 'PageTitleLabel';
            app.WelcomeLabel.Text = 'Welcome';
            app.HelpIcon = uiimage(app.IntroTab);
            app.HelpIcon.ImageSource = 'hjelp2.png';
            app.HelpIconLabel = uilabel(app.IntroTab);
            app.HelpIconLabel.Text = 'Press for help';
            app.EyeIcon = uiimage(app.IntroTab);
            app.EyeIcon.ImageSource = 'look2.png';
            app.EyeIconLabel = uilabel(app.IntroTab);
            app.EyeIconLabel.Text = 'Press for preview';
            app.InfoIcon = uiimage(app.IntroTab);
            app.InfoIcon.ImageSource = 'info2.png';
            app.InfoIconLabel = uilabel(app.IntroTab);
            app.InfoIconLabel.Text = 'Press for information';
            app.LoadingImage = uiimage(app.IntroTab);
            app.LoadingImage.ImageSource = 'loading.gif';
            app.Image9 = uiimage(app.IntroTab);
            app.Image9.Visible = 'off';
            app.Image9.ImageSource = 'Choices5.png';
            app.StartButton = uibutton(app.IntroTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Get started!';
            app.StartButton_2 = uibutton(app.IntroTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_2.Visible = 'off';
            app.StartButton_2.Text = 'Give me all the options!';
            app.StartButton_3 = uibutton(app.IntroTab, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_3.Visible = 'off';
            app.StartButton_3.Text = 'Make it quick!';
            app.AddonsTab = uitab(app.TabGroup);
            app.AddonsTab.Title = 'Addons';
            app.AddonsPanel = uipanel(app.AddonsTab);
            app.UITable = uitable(app.AddonsPanel);
            app.UITable.ColumnName = {'Toolbox Name'; 'Description'; 'Optionality'};
            app.UITable.RowName = {};
            app.Label_13 = uilabel(app.AddonsTab);
            app.Label_13.Tag = 'PageDescriptionLabel';
            app.Label_13.Text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';
            app.DownloadToolboxesLabel_2 = uilabel(app.AddonsTab);
            app.DownloadToolboxesLabel_2.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel_2.Text = 'Download Toolboxes';
            app.ShowAdvancedOptionsButton = uibutton(app.AddonsTab, 'state');
            app.ShowAdvancedOptionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowAdvancedAddonOptionsButtonPushed, true);
            app.ShowAdvancedOptionsButton.Text = 'Show Advanced Options...';
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.ProjectPanel = uipanel(app.ProjectTab);
            app.ChangeProjectFolderButton = uibutton(app.ProjectPanel, 'push');
            app.ChangeProjectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeProjectFolderButtonPushed, true);
            app.ChangeProjectFolderButton.Text = 'Change Folder';
            app.GivetheprojectadescriptionLabel = uilabel(app.ProjectPanel);
            app.GivetheprojectadescriptionLabel.Visible = 'off';
            app.GivetheprojectadescriptionLabel.Text = 'Give the project a description';
            app.ProjectNameEditField = uieditfield(app.ProjectPanel, 'text');
            app.ProjectNameEditField.Visible = 'off';
            app.LocalpathtosaveprojectconfigurationsLabel = uilabel(app.ProjectPanel);
            app.LocalpathtosaveprojectconfigurationsLabel.Text = 'Local path (to save project configurations)';
            app.LocalPathEditField = uieditfield(app.ProjectPanel, 'text');
            app.EnterashortprojectnameLabel = uilabel(app.ProjectPanel);
            app.EnterashortprojectnameLabel.Tooltip = {'(a-z, A-Z, 1-9, _)'};
            app.EnterashortprojectnameLabel.Text = 'Enter a short project name';
            app.ProjectLabelEditField = uieditfield(app.ProjectPanel, 'text');
            app.ProjectLabelEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectLabelEditFieldValueChanged, true);
            app.ProjectLabelEditField.ValueChangingFcn = createCallbackFcn(app, @ProjectLabelEditFieldValueChanging, true);
            app.ProjectLabelEditField.Tooltip = {'(a-z, A-Z, 1-9, _)'};
            app.CreateNewProjectButton = uibutton(app.ProjectPanel, 'push');
            app.CreateNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewProjectButtonValueChanged, true);
            app.CreateNewProjectButton.Text = 'Create New Project';
            app.Label_3 = uilabel(app.ProjectTab);
            app.Label_3.Tag = 'PageDescriptionLabel';
            app.Label_3.Text = {'Enter project name and a directory where configuration data will be saved.'; ''; ''};
            app.ProjectHeading = uilabel(app.ProjectTab);
            app.ProjectHeading.Tag = 'PageTitleLabel';
            app.ProjectHeading.Text = 'Create Project';
            app.Label_12 = uilabel(app.ProjectTab);
            app.Label_12.Text = '';
            app.DataLocationsTab = uitab(app.TabGroup);
            app.DataLocationsTab.Title = 'Data Locations';
            app.DataLocationPanel = uipanel(app.DataLocationsTab);
            app.AddDataLocationButton = uibutton(app.DataLocationsTab, 'push');
            app.AddDataLocationButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataLocationButtonPushed, true);
            app.AddDataLocationButton.Text = '+';
            app.Label_14 = uilabel(app.DataLocationsTab);
            app.Label_14.Text = 'Optional: You can add multiple data locations. This is relevant if different types of raw data, i.e imaging data and behavior data, are stored in different locations, or for processed data etc.';
            app.DataLocationDecriptionLabel = uilabel(app.DataLocationsTab);
            app.DataLocationDecriptionLabel.Tag = 'PageDescriptionLabel';
            app.DataLocationDecriptionLabel.Text = {'Enter a root directory where raw data for the new project is located.'; ''};
            app.DataLocationsTitleLabel = uilabel(app.DataLocationsTab);
            app.DataLocationsTitleLabel.Tag = 'PageTitleLabel';
            app.DataLocationsTitleLabel.Text = 'Add Data Locations';
            app.FolderHierarchyTab = uitab(app.TabGroup);
            app.FolderHierarchyTab.Title = 'Folder Organization';
            app.FolderOrganizationInputPanel = uipanel(app.FolderHierarchyTab);
            app.Label_5 = uilabel(app.FolderHierarchyTab);
            app.Label_5.Tag = 'PageDescriptionLabel';
            app.Label_5.Text = 'How are data subfolders organized within the root folder? Add new subfolder level(s) using the + button.';
            app.SpecifyDataOrganizationLabel = uilabel(app.FolderHierarchyTab);
            app.SpecifyDataOrganizationLabel.Tag = 'PageTitleLabel';
            app.SpecifyDataOrganizationLabel.Text = 'Specify Data Organization';
            app.PreviewFolderListImage = uiimage(app.FolderHierarchyTab);
            app.PreviewFolderListImage.ImageClickedFcn = createCallbackFcn(app, @ViewFolderListingButtonPushed, true);
            app.PreviewFolderListImage.Tooltip = {'Press to preview detected folders...'};
            app.PreviewFolderListImage.ImageSource = 'look2.png';
            app.ShowFilterOptionsButton = uibutton(app.FolderHierarchyTab, 'state');
            app.ShowFilterOptionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowFilterOptionsButtonPushed, true);
            app.ShowFilterOptionsButton.Text = 'Show Filter Options...';
            app.Label_15 = uilabel(app.FolderHierarchyTab);
            app.Label_15.Text = 'Optional:  Do you want to exclude some folders? Apply inclusion and exclusion criteria on foldernames under filter options.';
            app.SelectdatalocationDropDownLabel = uilabel(app.FolderHierarchyTab);
            app.SelectdatalocationDropDownLabel.Text = 'Select data location';
            app.SelectDataLocationDropDown = uidropdown(app.FolderHierarchyTab);
            app.SelectDataLocationDropDown.Items = {'Rawdata'};
            app.SelectDataLocationDropDown.ValueChangedFcn = createCallbackFcn(app, @DataLocationSelectionChanged, true);
            app.SelectDataLocationDropDown.Value = 'Rawdata';
            app.InfoIcon_2 = uiimage(app.FolderHierarchyTab);
            app.InfoIcon_2.ImageClickedFcn = createCallbackFcn(app, @InfoIcon_2ImageClicked, true);
            app.InfoIcon_2.ImageSource = 'info2.png';
            app.SchemaTab = uitab(app.TabGroup);
            app.SchemaTab.Title = 'Schema';
            app.SelectSessionSchemaLabel = uilabel(app.SchemaTab);
            app.SelectSessionSchemaLabel.Tag = 'PageTitleLabel';
            app.SelectSessionSchemaLabel.Text = 'Select Session Schema';
            app.Label_4 = uilabel(app.SchemaTab);
            app.Label_4.Tag = 'PageDescriptionLabel';
            app.Label_4.Text = 'We will create a list of experimental sessions for your project. Select a session schema which is suitable for the experiments of this project (The schema specifies a list of table variables that will be displayed for each session in the list).';
            app.SchemaPanel = uipanel(app.SchemaTab);
            app.SelectSchemaTemplateLabel = uilabel(app.SchemaPanel);
            app.SelectSchemaTemplateLabel.Text = 'Select Schema Template';
            app.SelectSchemaTemplateDropDown = uidropdown(app.SchemaPanel);
            app.SelectSchemaTemplateDropDown.Items = {'Two Photon Session', 'Behavioral Training Session'};
            app.SelectSchemaTemplateDropDown.Value = 'Two Photon Session';
            app.SchemaHelpImageButton = uiimage(app.SchemaPanel);
            app.SchemaHelpImageButton.ImageClickedFcn = createCallbackFcn(app, @SchemaHelpImageButtonClicked, true);
            app.SchemaHelpImageButton.Tag = 'InstallAddon';
            app.SchemaHelpImageButton.ImageSource = 'hjelp2.png';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.MetadataHeading = uilabel(app.MetadataTab);
            app.MetadataHeading.Tag = 'PageTitleLabel';
            app.MetadataHeading.Text = 'Detect Data Information';
            app.Label_7 = uilabel(app.MetadataTab);
            app.Label_7.Tag = 'PageDescriptionLabel';
            app.Label_7.Text = 'Select folders and the part of the foldernames that uniquely identifies the specified information:';
            app.MetadataPanel = uipanel(app.MetadataTab);
            app.ShowAdvancedOptionsButton_2 = uibutton(app.MetadataTab, 'state');
            app.ShowAdvancedOptionsButton_2.ValueChangedFcn = createCallbackFcn(app, @ShowAdvancedOptionsButton_2ValueChanged, true);
            app.ShowAdvancedOptionsButton_2.Text = 'Show Advanced Options...';
            app.VariablesTab = uitab(app.TabGroup);
            app.VariablesTab.Title = 'Variables';
            app.DataVariablesLabel = uilabel(app.VariablesTab);
            app.DataVariablesLabel.Tag = 'PageTitleLabel';
            app.DataVariablesLabel.Text = 'Data Variables';
            app.Label_6 = uilabel(app.VariablesTab);
            app.Label_6.Tag = 'PageDescriptionLabel';
            app.Label_6.Text = 'Ok, almost there! Please specify a part of the filename that can uniquely identify the file containing the raw two-photon imaging series.';
            app.VariableEditorPanel = uipanel(app.VariablesTab);
            app.Label_16 = uilabel(app.VariablesTab);
            app.Label_16.Text = 'Optional: Show all pipeline variables and add your own custom variables.';
            app.AddVariablesButton = uibutton(app.VariablesTab, 'push');
            app.AddVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariablesButtonPushed, true);
            app.AddVariablesButton.Text = '+';
            app.ShowPresetVariablesButton = uibutton(app.VariablesTab, 'state');
            app.ShowPresetVariablesButton.ValueChangedFcn = createCallbackFcn(app, @ShowPresetVariablesButtonValueChanged, true);
            app.ShowPresetVariablesButton.Text = 'Show all variables...';
            app.AddonsAdvancedTab = uitab(app.TabGroup);
            app.AddonsAdvancedTab.Title = 'Addons (Advanced)';
            app.AddonsPanelAdvanced = uipanel(app.AddonsAdvancedTab);
            app.Label_2 = uilabel(app.AddonsAdvancedTab);
            app.Label_2.Tag = 'PageDescriptionLabel';
            app.Label_2.Text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
            app.SaveMatlabPathButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.SaveMatlabPathButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatlabPathButtonPushed, true);
            app.SaveMatlabPathButton.Text = 'Save Matlab Path';
            app.DownloadToolboxesLabel = uilabel(app.AddonsAdvancedTab);
            app.DownloadToolboxesLabel.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel.Text = 'Download Toolboxes';
            app.DownloadSelectedButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.DownloadSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadSelectedButtonPushed, true);
            app.DownloadSelectedButton.Text = 'Download Selected';
            app.DownloadAllButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.DownloadAllButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadAllButtonPushed, true);
            app.DownloadAllButton.Text = 'Download All';
            app.ToolboxTab = uitab(app.TabGroup);
            app.ToolboxTab.Title = 'Toolbox';
            app.Label_11 = uilabel(app.ToolboxTab);
            app.Label_11.Tag = 'PageDescriptionLabel';
            app.Label_11.Text = 'Some MATLAB toolboxes are required for Nansen to work properly. Before we continue, please use MATLAB''s Addon Manager to add the toolboxes listed below. ';
            app.InstallToolboxesLabel = uilabel(app.ToolboxTab);
            app.InstallToolboxesLabel.Tag = 'PageTitleLabel';
            app.InstallToolboxesLabel.Text = 'Install Toolboxes';
            app.Panel = uipanel(app.ToolboxTab);
            app.Image5 = uiimage(app.Panel);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = 'matlab_addon_explorer.png';
            app.AddonInstallationNote = uilabel(app.Panel);
            app.AddonInstallationNote.Text = 'Note: MATLAB will be restarted after each installation';
            app.SkipButton = uibutton(app.Panel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.AddonInstructionLabel = uilabel(app.ToolboxTab);
            app.AddonInstructionLabel.Text = 'Go to MATLAB''s Home tab -> Add-Ons -> Get Add-Ons';
            app.HelpAddonManager = uiimage(app.ToolboxTab);
            app.HelpAddonManager.ImageClickedFcn = createCallbackFcn(app, @HelpAddonManagerClicked, true);
            app.HelpAddonManager.Tag = 'InstallAddon';
            app.HelpAddonManager.ImageSource = 'hjelp2.png';
            app.NavigationPanel = uipanel(app.NansenSetupUIFigure);
            app.PreviousButton = uibutton(app.NavigationPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.NavigationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.NansenLogoImage = uiimage(app.NavigationPanel);
            app.NansenLogoImage.ImageSource = 'nansen_logo2.png';
            app.Image6 = uiimage(app.NansenSetupUIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'folder_hierarchy_illustration.png';
            app.Image7 = uiimage(app.NansenSetupUIFigure);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = 'close.png';
            % Show the figure after all components are created
            app.NansenSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NansenSetupApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NansenSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NansenSetupUIFigure)
        end
    end
end