classdef NansenSetupApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NansenSetupUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        IntroTab                       matlab.ui.container.Tab
        IntroText                      matlab.ui.control.Label
        WelcomeLabel                   matlab.ui.control.Label
        HelpIcon                       matlab.ui.control.Image
        HelpIconLabel                  matlab.ui.control.Label
        EyeIcon                        matlab.ui.control.Image
        EyeIconLabel                   matlab.ui.control.Label
        InfoIcon                       matlab.ui.control.Image
        InfoIconLabel                  matlab.ui.control.Label
        LoadingImage                   matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        StartButton                    matlab.ui.control.Button
        StartButton_2                  matlab.ui.control.Button
        StartButton_3                  matlab.ui.control.Button
        AddonsTab                      matlab.ui.container.Tab
        AddonsPanel                    matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        Label_13                       matlab.ui.control.Label
        DownloadToolboxesLabel_2       matlab.ui.control.Label
        ShowMoreOptionsButton          matlab.ui.control.StateButton
        ProjectTab                     matlab.ui.container.Tab
        ProjectPanel                   matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        ProjectHeading                 matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        DataLocationsTab               matlab.ui.container.Tab
        DataLocationPanel              matlab.ui.container.Panel
        Label_14                       matlab.ui.control.Label
        DataLocationDecriptionLabel    matlab.ui.control.Label
        DataLocationsTitleLabel        matlab.ui.control.Label
        FolderHierarchyTab             matlab.ui.container.Tab
        FolderOrganizationInputPanel   matlab.ui.container.Panel
        Label_5                        matlab.ui.control.Label
        SpecifyDataOrganizationLabel   matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        FolderHierarchyExampleInfoButton  matlab.ui.control.Image
        MetadataTab                    matlab.ui.container.Tab
        MetadataHeading                matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        MetadataPanel                  matlab.ui.container.Panel
        VariablesTab                   matlab.ui.container.Tab
        DataVariablesLabel             matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        VariableEditorPanel            matlab.ui.container.Panel
        Label_16                       matlab.ui.control.Label
        SchemaTab                      matlab.ui.container.Tab
        SelectSessionSchemaLabel       matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        SchemaPanel                    matlab.ui.container.Panel
        SelectSchemaTemplateLabel      matlab.ui.control.Label
        SelectSchemaTemplateDropDown   matlab.ui.control.DropDown
        SchemaHelpButton               matlab.ui.control.Image
        AddonsAdvancedTab              matlab.ui.container.Tab
        AddonsPanelAdvanced            matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        SaveMatlabPathButton           matlab.ui.control.Button
        DownloadToolboxesLabel         matlab.ui.control.Label
        DownloadSelectedButton         matlab.ui.control.Button
        DownloadAllButton              matlab.ui.control.Button
        ToolboxTab                     matlab.ui.container.Tab
        Label_11                       matlab.ui.control.Label
        InstallToolboxesLabel          matlab.ui.control.Label
        ToolboxPanel                   matlab.ui.container.Panel
        MatlabAddonExplorerScreenshot  matlab.ui.control.Image
        AddonInstallationNote          matlab.ui.control.Label
        SkipToolboxPageButton          matlab.ui.control.Button
        AddonInstructionLabel          matlab.ui.control.Label
        HelpAddonManager               matlab.ui.control.Image
        NavigationPanel                matlab.ui.container.Panel
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        NansenLogoImage                matlab.ui.control.Image
        FolderHierarchyExampleImage    matlab.ui.control.Image
        CloseDialogButton              matlab.ui.control.Image
    end


    % Color & Appearance Properties Todo: Should be a theme...
    properties
        
        FontName = 'Segoe UI';
        
        % Eksempel for hex definisjon:
        % GenericButtonFontColor = hex2rgb('000000');
        
        % Standard button
        GenericButtonFontColor = [0,0,0];
        GenericButtonBackgroundColor = [0.9,0.9,0.9];
        
        % Install button
        InstallButtonFontColor = [0.8784 0.8784 0.7059];
        InstallButtonBackgroundColor = [0 0.6 0];
        
        % Dropdown menu
        DropdownMenuBackgroundColor = [1,1,1];
        
    end
    
    
    properties (Access = private) % Data models & UI Interfaces for tabs
        
        ProjectManager   nansen.config.project.ProjectManager               % Manager for projects
        ProjectManagerUI nansen.config.project.ProjectManagerUI             % User interface for the project manager
        
        AddonManager   % Interface for installing addons/toolboxes
        AddonUiTable   % UI Table for displaying list of supported addons
        
        DataLocationModel
        DataLocationModelUI
        FolderOrganizationUI
        MetadataInitializationUI
        
        VariableModel
        VariableModelUI
        
        TabUpdateRequired = struct('IntroTab', false, 'AddonsTab', false, ...
            'ProjectTab', false, 'SchemaTab', false, ...
            'DataLocationsTab', true, 'FolderHierarchyTab', true, ...
            'MetadataTab', false, 'VariablesTab', false)
        
        ProjectCreatedListener
        ProjectChangedListener
        
    end
    
    
    properties (Access = private) % App components that are created programmatically
        
        PageIndicator % Description
        NextButton2
        PreviousButton2
        
    end
    
    
    methods (Access = private) % Methods for creating pages
        
        function createPagesOnStartup(app)
            
            % Create table for addon/toolbox manager
            app.createAddonTable()

            % Create page for project manager
            app.ProjectManagerUI = nansen.config.project.ProjectManagerUI(app.ProjectPanel);
            app.ProjectManager = app.ProjectManagerUI.ProjectManager;
            
            app.ProjectChangedListener = listener(app.ProjectManagerUI, ...
                'ProjectChanged', @app.onProjectChanged);
            
            % Skip the next steps...
            if app.ProjectManager.NumProjects == 0
                return
            end

            
            app.createDataLocationsUI()
            
            
            % Todo: create variable configurator
            app.createVariableEditorPage()
                        

        end 
        
        function createToolboxPage(app, toolboxNames)
            
            xPos = 70;
            yPos = 220;
            
            editFieldHeight = 22;
            editFieldSpacing = 10;
            
            numToolboxes = numel(toolboxNames);
            
            for i = 1:numToolboxes
                number = uilabel(app.ToolboxPanel);
                number.Text = sprintf('%d.', i);
                number.Position = [xPos-20, yPos, 22,22];
                
                nameField = uieditfield(app.ToolboxPanel, 'text');
                nameField.Value = toolboxNames{i};
                nameField.Editable = false;

%                 nameField = uilabel(app.ToolboxPanel);
%                 nameField.Text = toolboxNames{i};

                nameField.BackgroundColor = [0.9412 0.9412 0.9412];
                w = numel(toolboxNames{i}) * 12 * 0.55;
                nameField.Position = [xPos, yPos, w, editFieldHeight];
                
                yPos = yPos - editFieldHeight - editFieldSpacing;
            end

        end
        
        function createAddonTable(app)
        %createAddonTable Create a table of supported addons

            app.AddonManager = nansen.setup.model.Addons;
            
            % This will create the advanced options for addon installation
            hPanel = app.AddonsPanelAdvanced;
            args = {hPanel, app.AddonManager};
            app.AddonUiTable = nansen.setup.view.AddonTable(args{:});
            
            
            % This will create the default view for addon installation
            data = app.AddonManager.AddonList;
            isInstalled = [data.IsInstalled];
            
            names = {data(~isInstalled).Name}';
            descriptions = {data(~isInstalled).Description}';
            isRequired = [data(~isInstalled).IsRequired]';
            
            requiredLabel = cell(size(names));
            requiredLabel(isRequired) = {'Required'};
            requiredLabel(~isRequired) = {'Optional'};
            
            if isempty(names)
                app.UITable.Visible = 'off';
                % Todo: Just skip page altogether
                app.Label_13.Text = 'All packages are installed. You can proceed';
            else
                app.UITable.Data = [names, descriptions, requiredLabel];
            end
            
            % app.UITable.Data = [names, isRequired];
            
            
        end

        function createDataLocationsUI(app)
            
            if ~isempty( app.DataLocationModelUI ) % Delete if it exists
                delete( app.DataLocationModelUI )
            end
            
            % Get current data location model....
            app.DataLocationModel = nansen.config.dloc.DataLocationModel;
            
            % Create components for the DataLocationModel configuration
            h = nansen.config.dloc.DataLocationModelApp(...
                'DataLocationModel', app.DataLocationModel, ...
                'ControlPanels', [app.DataLocationPanel, app.FolderOrganizationInputPanel, app.MetadataPanel], ...
                'TabGroup', app.TabGroup, ...
                'TabList', [app.DataLocationsTab, app.FolderHierarchyTab, app.MetadataTab]);
            
            % Todo: assign to properties
            h.createUIModules(1)
            h.createUIModules(2, 'FolderListViewerActive', true)
            h.createUIModules(3)
            
            app.DataLocationModelUI = h;
            
        end
        
        function createVariableEditorPage(app)
        %createVariableEditorPage create ui for variable model.
        
            import nansen.config.varmodel.*
            
            if ~isempty( app.VariableModelUI ) % Delete if it exists
                delete( app.VariableModelUI )
            end

            app.VariableModel = nansen.config.varmodel.VariableModel();
            app.VariableModel.addDataLocationModel(app.DataLocationModel);
            
            
            args = {'DataLocationModel', app.DataLocationModel, ...
                'VariableModel', app.VariableModel, ...
                'Data', app.VariableModel.Data };
            
            app.VariableModelUI = nansen.config.varmodel.VariableModelUI(app.VariableEditorPanel, args{:});
            app.VariableModelUI.hidePresetVariables()
            app.VariableModelUI.UIButton_ToggleVariableVisibility.Text = 'Show all variables...';
            app.VariableModelUI.UIButton_AddVariable.Enable = 'off';

        end


    end
    
    methods (Access = private) % Methods for updating pages
    
        function onChangePagePressed(app, direction)
        %onChangePagePressed Callback for clicks on next/previous buttons
        %
        %   Initialize changing page
        
            switch direction
                case 'previous'
                    incr = -1;
                case 'next'
                    incr = 1;
            end
            
            % Get the current tab.
            currentTab = app.TabGroup.SelectedTab;
            
            % Find tab number of current tab and new tab
            currentTabNum = find(ismember(app.TabGroup.Children, currentTab));
            newTabNum = currentTabNum + incr;
            
            % Don't switch to tabs outside range
            if newTabNum < 1 || newTabNum > numel(app.TabGroup.Children)
                return
            end
            
            app.changeTab(currentTabNum, newTabNum)
            
        end
        
        function onPageSwitcherPressed(app, src, evt)
        %onPageSwitcherPressed Callback for clicks on pageswitcher bar    
            %   Initialize changing page
            app.changeTab(evt.OldPageNumber, evt.NewPageNumber)
        end
        
        function changeTab(app, currentTabNum, newTabNum)
        %changeTab Change tabs and do all the internal work needed.
        
            % Todo: Use tab numbers or tab titles as inputs?
            
            currentTab = app.TabGroup.Children(currentTabNum);
            newTab = app.TabGroup.Children(newTabNum);
            
            % Check if tab is completed and we can move on
            isCompleted = app.checkIfTabCompleted(currentTab.Title);
            if ~isCompleted
                return
            end
            
            app.exitTab(currentTab.Title)
            
            % Change the selected tab.
            app.TabGroup.SelectedTab = newTab;
            
            % Call method to update the selected tab.
            app.enterTab(newTab)
            
            app.PageIndicator.changePage(newTabNum)
            
            % Turn off navigation bar if going back to Welcome page 
            if newTabNum == 1
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'off';
                app.PreviousButton2.Visible = 'off';
            else
                app.NavigationPanel.Visible = 'on';
                app.NextButton2.Visible = 'on';
                app.PreviousButton2.Visible = 'on';
            end
            
% %             if newTabNum == 8
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.finish;
% %             else
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.next2;
% %             end
            
        end
        
        function isCompleted = checkIfTabCompleted(app, tabName)
            
            isCompleted = true;
            
            switch tabName
                case 'Project'
                    %return
                    
                    if app.ProjectManager.NumProjects ~= 0
                       return
                       
                    elseif ~app.ProjectManagerUI.isProjectInformationEntered()
                        msg = 'You need to enter a project name before proceeding';
                        title = 'Project name missing';
                        isCompleted = false;
                    else
                        if ~app.ProjectManagerUI.wasProjectCreated()
                            selection = uiconfirm(app.NansenSetupUIFigure, 'Create project now?', 'Create Project', 'Icon', 'hjelp2.png');
                            switch selection
                                case 'OK'
                                    % Todo: need confirmation that this
                                    % worked
                                    app.ProjectManagerUI.createProject()
                                    pause(0.05)
                                    % Todo: Give feedback to user that the
                                    % project was created.
                                case 'Cancel'
                                    msg = '';
                                    isCompleted = false;
                            end
                        end
                    end
                    
                case 'Data Locations'
                    dlModule = app.DataLocationModelUI.UIModule{1};
                    [isCompleted, msg] = dlModule.isTableCompleted();

            end
            
            
            if ~isCompleted && ~isempty(msg)
                uialert(app.NansenSetupUIFigure, msg, 'Please enter missing information', 'Icon', 'info')
            end
            
        end
        
        function exitTab(app, tabName)
        %exitTab Check for changes on interdependent tabs
        %
        %   If there has been changes to the data model on a given tab, a
        %   dependent tab is flagged for update and the datamodel is
        %   updated.
            
            switch tabName
                
                case 'Addons'
                    if app.ShowMoreOptionsButton.Value

                        if app.AddonManager.IsDirty
                            message = 'Do you want to add downloaded toolboxes to the matlab path?';
                            title = 'Update Matlab''s savepath?';
                
                            selection = uiconfirm(app.NansenSetupUIFigure, message, ...
                                title, 'Options',{'Yes','Skip'},...
                                'DefaultOption',1,'CancelOption',2);
                            
                            if strcmp(selection, 'Yes')
                                savepath
                                app.AddonManager.markClean()
                                app.AddonManager.restoreAddToPathOnInitFlags()
                            end
                        end
                        
                    else
                        % By default, we should install all toolboxes
                        app.DownloadAllButtonPushed([])
                        savepath
                        app.AddonManager.markClean()
                        app.AddonManager.restoreAddToPathOnInitFlags()
                    end
                    
                case 'Project'


                case 'Data Locations'
                    app.DataLocationModelUI.UIModule{1}.setInactive()
                    
                case 'Folder Organization'
                    app.DataLocationModelUI.UIModule{2}.setInactive()

                case 'Metadata'
                    app.DataLocationModelUI.UIModule{3}.setInactive()
                    
            end
            
            
        end
        
        function enterTab(app, uiTab)
        %enterTab Update tab when it is selected                
            
            switch uiTab.Title
                case 'Data Locations'
                    if ~isempty(app.DataLocationModelUI) % Precaution
                        app.DataLocationModelUI.UIModule{1}.setActive()
                    end
                    
                case 'Folder Organization'
                    app.DataLocationModelUI.UIModule{2}.setActive()
                    
                case 'Metadata'
                    app.DataLocationModelUI.UIModule{3}.setActive()
                    
                case 'Variables'
                    if app.TabUpdateRequired.VariablesTab
                        %app.updateVariablesTab()
                        app.TabUpdateRequired.VariablesTab = false;
                    end

            end
        
        end
        
        function finishSetup(app)
        %finishSetup Finish the setup based on user configurations
        
            % Todo: Save the Data Location model and the filepath settings.
            
            % Todo: Make external function
                        
% % % % % % Save changes from data location and folder organization pages. 
            
            % Save the data location model
            
            for i = 2:3
                if ~isempty(app.DataLocationModelUI.UIModule{i})
                    app.DataLocationModelUI.UIModule{i}.updateDataLocationModel()
                    app.DataLocationModelUI.UIModule{i}.markClean()
                end
            end
            
            app.DataLocationModel.refreshFilePath()
            app.DataLocationModel.save()

% % % % % % Save changes from data variables page 
            S = app.VariableModelUI.getUpdatedTableData();
            app.VariableModel.setVariableList(S)
            app.VariableModel.save()
            
            % Todo: Get schema based on selection
            sessionSchema = @nansen.metadata.type.Session;
            
            try
                % Does metatable exist from before?
                MT = nansen.metadata.MetaTableCatalog;
                
                if ~isempty(MT.Table)
                    metaTableExists = any( strcmp(MT.Table.MetaTableName, 'Session') );
                else
                    metaTableExists = false;
                end
                
                createMetaTable = true;
                
                if metaTableExists
                    selection = uiconfirm(app.NansenSetupUIFigure, ...
                        ['A Metatable for this project already exists. ' ...
                        'Do you want to replace the existing table?'], ...
                        'Override Existing Metatable?', ...
                        'Options', {'Replace', 'Keep Current', 'Cancel'}, ...
                        'Icon', 'hjelp2.png');
                    
                    switch selection
                        case 'Replace'
                            createMetaTable = true;
                        case 'Keep Current'
                            createMetaTable = false;
                        otherwise
                            return
                    end
                    
                end
                
                if createMetaTable
                    d = uiprogressdlg(app.NansenSetupUIFigure, ...
                        'Title', 'Detecting Sessions', 'Indeterminate','on');
                    wasAborted = nansen.config.initializeSessionTable(app.DataLocationModel, sessionSchema, d, app.NansenSetupUIFigure);
                    close(d)

                    if wasAborted
                        uialert(app.NansenSetupUIFigure, 'Aborted while creating a session table', 'Aborted')
                        return
                    end
                    
                end
                
                % Open nansen app
                nansen
                
                % Close configuration app
                %close(app.NansenSetupUIFigure)
            catch ME
                uialert(app.NansenSetupUIFigure, ME.message, ME.identifier)
                rethrow(ME)
            end    
            
            
        end
        
    end
        
    methods
        
        function onProjectChanged(app, src, evt)
        %onProjectCreated Update dependent pages when current project change
        
            
            % Show waitbar...
            d = uiprogressdlg(app.NansenSetupUIFigure, ...
                'Title', 'Updating pages...', 'Indeterminate','on');
            
            d.Message = 'Initializing DataLocation Model for project...';
            app.createDataLocationsUI()
            
            d.Message = 'Initializing Variable Model for project...';
            app.createVariableEditorPage()
            
            delete(d)
            
            
            % Todo: necessary?
            app.TabUpdateRequired.DataLocationsTab = true;
            app.TabUpdateRequired.FolderHierarchyTab = true;
            app.TabUpdateRequired.MetadataTab = true;
            app.TabUpdateRequired.VariablesTab = true;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPanel)
            
            hFig = app.NansenSetupUIFigure; % Just to shorten variablename
            uim.utility.centerFigureOnScreen(hFig)
            
            S = nansen.theme.getThemeColors('deepblue');


            app.NavigationPanel.BackgroundColor = S.HeaderBgColor;

            % Find and set background colors of tabs
            hTabs = app.TabGroup.Children;
            set(hTabs, 'BackgroundColor', S.FigureBgColor)

            % Find handles for all page title labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageTitleLabel'};
            hPageTitleLabels = findobj(hFig, tokens{:});
            set(hPageTitleLabels, 'BackgroundColor', S.HeaderBgColor, 'FontColor', S.HeaderFgColor, 'Position', [1,511,786,78], 'VerticalAlignment', 'bottom')
            
            % Find handles for all page description labels and remove background
            tokens = {'type', 'uilabel', 'tag', 'PageDescriptionLabel'};
            hPageDescriptionLabels = findobj(hFig, tokens{:});
            set(hPageDescriptionLabels, 'BackgroundColor', 'None')
            if ispc
                set(hPageDescriptionLabels, 'FontSize', 14)
            end
            
            app.FolderHierarchyExampleImage.Position(1:2) = [1,1];
            
            app.IntroText.Interpreter = 'html';
            
            app.StartButton.Text = 'Preparing...';
            app.StartButton.Enable = 'off';
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
            drawnow
            pause(0.05)
            
            figure(hFig)
            
            % Create pages
            app.createPagesOnStartup()
            
            
            % Set some button icons.
            iconPath = fullfile(matlabroot, 'toolbox', 'shared', 'controllib', 'general', 'resources', 'toolstrip_icons', 'Set_Path_24.png');
            app.SaveMatlabPathButton.Icon = iconPath;
            
            iconPath = fullfile(matlabroot, 'toolbox', 'shared', 'controllib', 'general', 'resources', 'toolstrip_icons', 'Import_24.png');
            app.DownloadSelectedButton.Icon = iconPath;
            app.DownloadAllButton.Icon = iconPath;
            
            
            % Customize appeareance of buttons and controls
            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uibutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)

            hButtons = findobj(app.NansenSetupUIFigure, 'type', 'uistatebutton');
            set(hButtons, 'FontColor', app.GenericButtonFontColor)
            set(hButtons, 'BackgroundColor', app.GenericButtonBackgroundColor)
            set(hButtons, 'FontName', app.FontName)
            
            hDropdown = findobj(app.NansenSetupUIFigure, 'type', 'uidropdown');
            set(hDropdown, 'BackgroundColor', app.DropdownMenuBackgroundColor)
            set(hDropdown, 'FontName', app.FontName)
            
            specialButtons = [app.SaveMatlabPathButton,app.DownloadSelectedButton,app.DownloadAllButton];
            set(specialButtons, 'FontColor', [0.15, 0.15, 0.15])
            set(specialButtons, 'BackgroundColor', 'w')
            
            
            uicc = uim.UIComponentCanvas(app.NavigationPanel);

            %obj.h.ButtonPrevious = uim.control.Button(uicc, 'String', 'Previous', 'Size', buttonSize, 'Location', 'southwest', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [180, 70, 0, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('previous'));
            %obj.h.ButtonNext = uim.control.Button(uicc, 'String', 'Next', 'Size', buttonSize, 'Location', 'southeast', 'HorizontalAlignment', 'left', 'HorizontalTextAlignment', 'center', 'Margin', [0, 70, 180, 0], 'ButtonDownFcn', @(s,e,token) obj.changePage('next'));
            
            % Create page indicator
            options = {'Size', [inf, 60], 'Margin', [13, 13, 20, 20], ...
                'Location', 'southwest', 'IndicatorSize', 10, ...
                'SizeMode', 'manual', 'BarColor', S.FigureBgColor, ...
                'FontColor', S.FigureBgColor, 'IndicatorColor', S.HeaderMidColor, ...
                'FontSize', 14, 'IndicatorSize', 12, 'BlockChangePage', true};

            pageNames = {app.TabGroup.Children(1:end-3).Title};
            app.PageIndicator = uim.widget.PageIndicator(uicc, pageNames, options{:});
            app.PageIndicator.ChangePageFcn = @app.onPageSwitcherPressed;
            
            
            ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
            
            app.PreviousButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.prev2, 'Position', [285,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [10,10,10,10], 'MechanicalAction', 'Latch when released', ...
                'Callback', @(src,evt) app.PreviousButtonPushed(evt), ...
                'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
            
            app.NextButton2 = uim.control.Button_(app.NavigationPanel, ...
                'Icon', ICONS.next2, 'Position', [425,15,60,60], ...
                'Size', [60,60], 'CornerRadius', 30, ...
                'Padding', [16,10,10,10], 'MechanicalAction', 'Latch when released',...
                'Callback', @(src,evt) app.NextButtonPushed(evt), ...
                 'Visible', 'off', ...
                 'Style', uim.style.buttonNansenSetup);
                %'Style', uim.style.buttonSymbol, ...
               
            
% %             btnBgColor = ones(1,3) .* [0    0.3020    0.4980];
% %             btnFgColor = ones(1,3) .* 0.98;
% %             
% %              
% %             app.NextButton.BackgroundColor = btnBgColor;
% %             app.PreviousButton.BackgroundColor = btnBgColor;
% %             app.NextButton.FontColor = btnFgColor;
% %             app.PreviousButton.FontColor = btnFgColor;
            
            drawnow

            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = '';
            
            app.StartButton.Text = 'Let''s go!';
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            
            app.StartButton.BackgroundColor = S.HeaderBgColor;
            app.StartButton.FontColor = S.FigureBgColor;
                        
% %             app.StartButton_3.BackgroundColor = S.HeaderBgColor;
% %             app.StartButton_3.FontColor = S.HeaderFgColor;
% %             app.StartButton_2.BackgroundColor = S.HeaderBgColor;
% %             app.StartButton_2.FontColor = S.HeaderFgColor;
% %             app.StartButton_2.Visible = 'on';
% %             app.StartButton_3.Visible = 'on';

%             lamps = [app.Lamp, app.Lamp_2, app.Lamp_3, app.Lamp_4, app.Lamp_5];
%             
%             for i = 1:5
%                 lamps(i).Color = bgColor;
%                 drawnow
%                 pause(1)
%             end
            
        end

        % Button pushed function: StartButton, StartButton_2, 
        % StartButton_3
        function StartButtonPushed(app, event)
            
            requiredToolboxes = {...
                'Statistics and Machine Learning Toolbox', ...
                'Image Processing Toolbox', ...
                'Parallel Computing Toolbox' ...
                }; 
            
            TF = nansen.setup.isToolboxInstalled(requiredToolboxes);
            %TF = false(1,3);
            if ~ all(TF)
                gotoNum = contains({app.TabGroup.Children.Title}, 'Toolbox');
                app.createToolboxPage(requiredToolboxes(~TF))
                app.TabGroup.SelectedTab = app.TabGroup.Children(gotoNum);
                app.PageIndicator.Visible = 'off';
                return
            end
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            app.PageIndicator.changePage(2)
            
            %app.NavigationPanel.Visible = 'on';
            app.NextButton2.Visible = 'on';
            app.PreviousButton2.Visible = 'on';


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            % Check if this is the last tab and we should finish.
            if strcmp(app.TabGroup.SelectedTab.Title, 'Variables')
                app.finishSetup()
                return
            end
            
            app.onChangePagePressed('next')
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.onChangePagePressed('previous')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        %TabGroupSelectionChanged Do things when tab is selected.
            
            app.enterTab(app.TabGroup.SelectedTab)
        end

        % Button pushed function: DownloadAllButton
        function DownloadAllButtonPushed(app, event)

            for i = 1:numel(app.AddonManager.AddonList)
                S = app.AddonManager.AddonList(i);
                if ~S.IsInstalled
                    % Call the Addon's Uitable install button callback
                    app.AddonUiTable.onInstallAddonPushed(S.Name, i)
                end
            end
        end

        % Button pushed function: DownloadSelectedButton
        function DownloadSelectedButtonPushed(app, event)
            
            for i = 1:numel(app.AddonManager.AddonList)
                S = app.AddonManager.AddonList(i);
                if ~S.IsInstalled && ismember(i, app.AddonUiTable.SelectedRows)
                    % Call the Addon's Uitable install button callback
                    app.AddonUiTable.onInstallAddonPushed(S.Name, i)
                end
            end
        end

        % Button pushed function: SaveMatlabPathButton
        function SaveMatlabPathButtonPushed(app, event)
                        
            message = 'This will permanently add the downloaded addons to the MATLAB search path.';
            title = 'Confirm Save';
            
            selection = uiconfirm(app.NansenSetupUIFigure, message, ...
                title, 'Options',{'Save Path','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            
            if strcmp(selection, 'Cancel'); return; end
            
            savepath
            
            app.AddonManager.markClean()
            app.AddonManager.restoreAddToPathOnInitFlags()
            
        end

        % Image clicked function: HelpAddonManager
        function HelpAddonManagerClicked(app, event)
            app.MatlabAddonExplorerScreenshot.Visible = 'on';
        end

        % Value changed function: ShowMoreOptionsButton
        function ShowAdvancedAddonOptionsButtonPushed(app, event)
            
            if event.Value
                text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
                app.AddonsPanel.Visible = 'off';
                parentTab = app.AddonsTab;
                app.ShowMoreOptionsButton.Text = 'Show Less Options...';
            else
                text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';
                app.AddonsPanel.Visible = 'on';
                parentTab = app.AddonsAdvancedTab;
                app.ShowMoreOptionsButton.Text = 'Show More Options...';
            end
            
            app.Label_13.Text = text;
            
            % Move advanced options to current tab. Easier to have
            % components on different tabs during development.
            app.DownloadSelectedButton.Parent = parentTab;
            app.DownloadAllButton.Parent = parentTab;
            app.SaveMatlabPathButton.Parent = parentTab;
            app.AddonsPanelAdvanced.Parent = parentTab;
        

            %newTabNum = contains({app.TabGroup.Children.Title}, 'Addons (Advanced)');
            %app.TabGroup.SelectedTab = app.TabGroup.Children(newTabNum);

        end

        % Image clicked function: SchemaHelpButton
        function SchemaHelpButtonClicked(app, event)
            
            schemaName = app.SelectSchemaTemplateDropDown.Value;
            msg = nansen.setup.getSchemaDefinition(schemaName);
                            
            hFigure = app.NansenSetupUIFigure;
            title = sprintf('Schema Definition');
            uialert(hFigure, msg, title, 'Icon', 'info2.png')
                
        end

        % Button pushed function: SkipToolboxPageButton
        function SkipToolboxPageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            
            %app.NavigationPanel.Visible = 'on';
            app.NextButton2.Visible = 'on';
            app.PreviousButton2.Visible = 'on';
            
            app.PageIndicator.Visible = 'on';
            app.PageIndicator.changePage(2)
        end

        % Image clicked function: FolderHierarchyExampleInfoButton
        function FolderHierarchyExampleInfoButtonImageClicked(app, event)
            app.FolderHierarchyExampleImage.Visible = 'on';
            app.CloseDialogButton.Visible = 'on';
        end

        % Close request function: NansenSetupUIFigure
        function NansenSetupUIFigureCloseRequest(app, event)
            
            abortClose = false;
            
            % Todo: Prompt to save changes!
            if ~isempty(app.DataLocationModelUI)
                abortClose = app.DataLocationModelUI.promptSaveChanges();
            end
            
            if abortClose
                return
            else
                delete(app)
            end
        end

        % Image clicked function: CloseDialogButton
        function CloseDialogButtonClicked(app, event)
            app.FolderHierarchyExampleImage.Visible = 'off';
            app.CloseDialogButton.Visible = 'off';

        end

        % Image clicked function: EyeIcon
        function EyeIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, preview options are available.', 'Info', 'Icon', 'info')
        end

        % Image clicked function: InfoIcon
        function InfoIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, additional information is available.', 'Info',  'Icon', 'info')
        end

        % Image clicked function: HelpIcon
        function HelpIconImageClicked(app, event)
            uialert(app.NansenSetupUIFigure, 'If you see this button during setup, additional help is available.', 'Info', 'Icon', 'info')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NansenSetupUIFigure = uifigure('Visible', 'off');
            app.NansenSetupUIFigure.Name = 'Nansen Setup';
            app.NansenSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @NansenSetupUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.NansenSetupUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.IntroText = uilabel(app.IntroTab);
            app.IntroText.Tag = 'PageDescriptionLabel';
            app.IntroText.Text = {'This dialog will guide you through the initial configuration.'; ''; 'The aims are'; '    1) Install required toolboxes'; '    2) Detect your existing data folders'; '    3) Create a metadata table for your existing data.'};
            app.WelcomeLabel = uilabel(app.IntroTab);
            app.WelcomeLabel.Tag = 'PageTitleLabel';
            app.WelcomeLabel.Text = 'Welcome';
            app.HelpIcon = uiimage(app.IntroTab);
            app.HelpIcon.ImageClickedFcn = createCallbackFcn(app, @HelpIconImageClicked, true);
            app.HelpIcon.ImageSource = 'hjelp2.png';
            app.HelpIconLabel = uilabel(app.IntroTab);
            app.HelpIconLabel.Text = 'Press for help';
            app.EyeIcon = uiimage(app.IntroTab);
            app.EyeIcon.ImageClickedFcn = createCallbackFcn(app, @EyeIconImageClicked, true);
            app.EyeIcon.ImageSource = 'look2.png';
            app.EyeIconLabel = uilabel(app.IntroTab);
            app.EyeIconLabel.Text = 'Press for preview';
            app.InfoIcon = uiimage(app.IntroTab);
            app.InfoIcon.ImageClickedFcn = createCallbackFcn(app, @InfoIconImageClicked, true);
            app.InfoIcon.ImageSource = 'info2.png';
            app.InfoIconLabel = uilabel(app.IntroTab);
            app.InfoIconLabel.Text = 'Press for information';
            app.LoadingImage = uiimage(app.IntroTab);
            app.LoadingImage.ImageSource = 'loading.gif';
            app.Image9 = uiimage(app.IntroTab);
            app.Image9.Visible = 'off';
            app.Image9.ImageSource = 'Choices5.png';
            app.StartButton = uibutton(app.IntroTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Get started!';
            app.StartButton_2 = uibutton(app.IntroTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_2.Visible = 'off';
            app.StartButton_2.Text = 'Give me all the options!';
            app.StartButton_3 = uibutton(app.IntroTab, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_3.Visible = 'off';
            app.StartButton_3.Text = 'Make it quick!';
            app.AddonsTab = uitab(app.TabGroup);
            app.AddonsTab.Title = 'Addons';
            app.AddonsPanel = uipanel(app.AddonsTab);
            app.UITable = uitable(app.AddonsPanel);
            app.UITable.ColumnName = {'Toolbox Name'; 'Description'; 'Optionality'};
            app.UITable.RowName = {};
            app.Label_13 = uilabel(app.AddonsTab);
            app.Label_13.Tag = 'PageDescriptionLabel';
            app.Label_13.Text = 'The following toolboxes will be downloaded and added to MATLAB''s search path.';
            app.DownloadToolboxesLabel_2 = uilabel(app.AddonsTab);
            app.DownloadToolboxesLabel_2.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel_2.Text = 'Download Toolboxes';
            app.ShowMoreOptionsButton = uibutton(app.AddonsTab, 'state');
            app.ShowMoreOptionsButton.ValueChangedFcn = createCallbackFcn(app, @ShowAdvancedAddonOptionsButtonPushed, true);
            app.ShowMoreOptionsButton.Text = 'Show More Options...';
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.ProjectPanel = uipanel(app.ProjectTab);
            app.Label_3 = uilabel(app.ProjectTab);
            app.Label_3.Tag = 'PageDescriptionLabel';
            app.Label_3.Text = {'Enter project name and a directory where configuration data will be saved.'; ''; ''};
            app.ProjectHeading = uilabel(app.ProjectTab);
            app.ProjectHeading.Tag = 'PageTitleLabel';
            app.ProjectHeading.Text = 'Create Project';
            app.Label_12 = uilabel(app.ProjectTab);
            app.Label_12.Text = '';
            app.DataLocationsTab = uitab(app.TabGroup);
            app.DataLocationsTab.Title = 'Data Locations';
            app.DataLocationPanel = uipanel(app.DataLocationsTab);
            app.Label_14 = uilabel(app.DataLocationsTab);
            app.Label_14.Text = 'Optional: You can add multiple data locations. This is relevant if different types of raw data, i.e imaging data and behavior data, are stored in different locations, or for processed data etc.';
            app.DataLocationDecriptionLabel = uilabel(app.DataLocationsTab);
            app.DataLocationDecriptionLabel.Tag = 'PageDescriptionLabel';
            app.DataLocationDecriptionLabel.Text = {'Enter a root directory where raw data for the new project is located.'; ''};
            app.DataLocationsTitleLabel = uilabel(app.DataLocationsTab);
            app.DataLocationsTitleLabel.Tag = 'PageTitleLabel';
            app.DataLocationsTitleLabel.Text = 'Add Data Locations';
            app.FolderHierarchyTab = uitab(app.TabGroup);
            app.FolderHierarchyTab.Title = 'Folder Organization';
            app.FolderOrganizationInputPanel = uipanel(app.FolderHierarchyTab);
            app.Label_5 = uilabel(app.FolderHierarchyTab);
            app.Label_5.Tag = 'PageDescriptionLabel';
            app.Label_5.Text = 'How are data subfolders organized within the root folder? Add new subfolder level(s) using the + button.';
            app.SpecifyDataOrganizationLabel = uilabel(app.FolderHierarchyTab);
            app.SpecifyDataOrganizationLabel.Tag = 'PageTitleLabel';
            app.SpecifyDataOrganizationLabel.Text = 'Specify Data Organization';
            app.Label_15 = uilabel(app.FolderHierarchyTab);
            app.Label_15.Text = 'Optional:  Do you want to exclude some folders? Apply inclusion and exclusion criteria on foldernames under filter options.';
            app.FolderHierarchyExampleInfoButton = uiimage(app.FolderHierarchyTab);
            app.FolderHierarchyExampleInfoButton.ImageClickedFcn = createCallbackFcn(app, @FolderHierarchyExampleInfoButtonImageClicked, true);
            app.FolderHierarchyExampleInfoButton.ImageSource = 'info2.png';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.MetadataHeading = uilabel(app.MetadataTab);
            app.MetadataHeading.Tag = 'PageTitleLabel';
            app.MetadataHeading.Text = 'Detect Data Information';
            app.Label_7 = uilabel(app.MetadataTab);
            app.Label_7.Tag = 'PageDescriptionLabel';
            app.Label_7.Text = 'Select folders and the part of the folder names that uniquely identifies the specified information:';
            app.MetadataPanel = uipanel(app.MetadataTab);
            app.VariablesTab = uitab(app.TabGroup);
            app.VariablesTab.Title = 'Variables';
            app.DataVariablesLabel = uilabel(app.VariablesTab);
            app.DataVariablesLabel.Tag = 'PageTitleLabel';
            app.DataVariablesLabel.Text = 'Data Variables';
            app.Label_6 = uilabel(app.VariablesTab);
            app.Label_6.Tag = 'PageDescriptionLabel';
            app.Label_6.Text = 'Ok, almost there! Please specify a part of the filename that can uniquely identify the file containing the raw two-photon imaging series.';
            app.VariableEditorPanel = uipanel(app.VariablesTab);
            app.Label_16 = uilabel(app.VariablesTab);
            app.Label_16.Text = 'Optional: Show all pipeline variables and add your own custom variables.';
            app.SchemaTab = uitab(app.TabGroup);
            app.SchemaTab.Title = 'Schema';
            app.SelectSessionSchemaLabel = uilabel(app.SchemaTab);
            app.SelectSessionSchemaLabel.Tag = 'PageTitleLabel';
            app.SelectSessionSchemaLabel.Text = 'Select Session Schema';
            app.Label_4 = uilabel(app.SchemaTab);
            app.Label_4.Tag = 'PageDescriptionLabel';
            app.Label_4.Text = 'We will create a list of experimental sessions for your project. Select a session schema which is suitable for the experiments of this project (The schema specifies a list of table variables that will be displayed for each session in the list).';
            app.SchemaPanel = uipanel(app.SchemaTab);
            app.SelectSchemaTemplateLabel = uilabel(app.SchemaPanel);
            app.SelectSchemaTemplateLabel.Text = 'Select Schema Template';
            app.SelectSchemaTemplateDropDown = uidropdown(app.SchemaPanel);
            app.SelectSchemaTemplateDropDown.Items = {'Two Photon Session', 'Behavioral Training Session'};
            app.SelectSchemaTemplateDropDown.Value = 'Two Photon Session';
            app.SchemaHelpButton = uiimage(app.SchemaPanel);
            app.SchemaHelpButton.ImageClickedFcn = createCallbackFcn(app, @SchemaHelpButtonClicked, true);
            app.SchemaHelpButton.Tag = 'InstallAddon';
            app.SchemaHelpButton.ImageSource = 'hjelp2.png';
            app.AddonsAdvancedTab = uitab(app.TabGroup);
            app.AddonsAdvancedTab.Title = 'Addons (Advanced)';
            app.AddonsPanelAdvanced = uipanel(app.AddonsAdvancedTab);
            app.Label_2 = uilabel(app.AddonsAdvancedTab);
            app.Label_2.Tag = 'PageDescriptionLabel';
            app.Label_2.Text = 'Select toolboxes/addons to download and add to the MATLAB search path.';
            app.SaveMatlabPathButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.SaveMatlabPathButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatlabPathButtonPushed, true);
            app.SaveMatlabPathButton.Text = 'Save Matlab Path';
            app.DownloadToolboxesLabel = uilabel(app.AddonsAdvancedTab);
            app.DownloadToolboxesLabel.Tag = 'PageTitleLabel';
            app.DownloadToolboxesLabel.Text = 'Download Toolboxes';
            app.DownloadSelectedButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.DownloadSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadSelectedButtonPushed, true);
            app.DownloadSelectedButton.Text = 'Download Selected';
            app.DownloadAllButton = uibutton(app.AddonsAdvancedTab, 'push');
            app.DownloadAllButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadAllButtonPushed, true);
            app.DownloadAllButton.Text = 'Download All';
            app.ToolboxTab = uitab(app.TabGroup);
            app.ToolboxTab.Title = 'Toolbox';
            app.Label_11 = uilabel(app.ToolboxTab);
            app.Label_11.Tag = 'PageDescriptionLabel';
            app.Label_11.Text = 'Some MATLAB toolboxes are required for Nansen to work properly. Before we continue, please use MATLAB''s Addon Manager to add the toolboxes listed below. ';
            app.InstallToolboxesLabel = uilabel(app.ToolboxTab);
            app.InstallToolboxesLabel.Tag = 'PageTitleLabel';
            app.InstallToolboxesLabel.Text = 'Install Toolboxes';
            app.ToolboxPanel = uipanel(app.ToolboxTab);
            app.MatlabAddonExplorerScreenshot = uiimage(app.ToolboxPanel);
            app.MatlabAddonExplorerScreenshot.Visible = 'off';
            app.MatlabAddonExplorerScreenshot.ImageSource = 'matlab_addon_explorer.png';
            app.AddonInstallationNote = uilabel(app.ToolboxPanel);
            app.AddonInstallationNote.Text = 'Note: MATLAB will be restarted after each installation';
            app.SkipToolboxPageButton = uibutton(app.ToolboxPanel, 'push');
            app.SkipToolboxPageButton.ButtonPushedFcn = createCallbackFcn(app, @SkipToolboxPageButtonPushed, true);
            app.SkipToolboxPageButton.Text = 'Skip';
            app.AddonInstructionLabel = uilabel(app.ToolboxTab);
            app.AddonInstructionLabel.Text = 'Go to MATLAB''s Home tab -> Add-Ons -> Get Add-Ons';
            app.HelpAddonManager = uiimage(app.ToolboxTab);
            app.HelpAddonManager.ImageClickedFcn = createCallbackFcn(app, @HelpAddonManagerClicked, true);
            app.HelpAddonManager.Tag = 'InstallAddon';
            app.HelpAddonManager.ImageSource = 'hjelp2.png';
            app.NavigationPanel = uipanel(app.NansenSetupUIFigure);
            app.PreviousButton = uibutton(app.NavigationPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.NavigationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.NansenLogoImage = uiimage(app.NavigationPanel);
            app.NansenLogoImage.ImageSource = 'nansen_logo2.png';
            app.FolderHierarchyExampleImage = uiimage(app.NansenSetupUIFigure);
            app.FolderHierarchyExampleImage.Visible = 'off';
            app.FolderHierarchyExampleImage.ImageSource = 'folder_hierarchy_illustration.png';
            app.CloseDialogButton = uiimage(app.NansenSetupUIFigure);
            app.CloseDialogButton.ImageClickedFcn = createCallbackFcn(app, @CloseDialogButtonClicked, true);
            app.CloseDialogButton.Visible = 'off';
            app.CloseDialogButton.ImageSource = 'close.png';
            % Show the figure after all components are created
            app.NansenSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NansenSetupApp2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NansenSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NansenSetupUIFigure)
        end
    end
end